classdef amode_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout8                matlab.ui.container.GridLayout
        CheckBox_detectpeak        matlab.ui.control.CheckBox
        Spinner_echorange          matlab.ui.control.Spinner
        EchoRangeSpinnerLabel      matlab.ui.control.Label
        Spinner_echoratio          matlab.ui.control.Spinner
        EchoRatioSpinnerLabel      matlab.ui.control.Label
        Spinner_minpeakheight      matlab.ui.control.Spinner
        Spinner_minpeakdist        matlab.ui.control.Spinner
        MinPeakDistSpinnerLabel    matlab.ui.control.Label
        Spinner_minpeakprom        matlab.ui.control.Spinner
        MinPeakPromSpinnerLabel    matlab.ui.control.Label
        MinPeakHeightSpinnerLabel  matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        Slider_timestamp           matlab.ui.control.Slider
        GridLayout6                matlab.ui.container.GridLayout
        Button_timestampprev       matlab.ui.control.Button
        Button_timestampnext       matlab.ui.control.Button
        EditField_timestamp        matlab.ui.control.NumericEditField
        Button_probenext           matlab.ui.control.Button
        Button_probeprev           matlab.ui.control.Button
        DropDown_probenumber       matlab.ui.control.DropDown
        Button_loaddata            matlab.ui.control.Button
        Button_play                matlab.ui.control.Button
        Button_pause               matlab.ui.control.Button
        Label_timestamp            matlab.ui.control.Label
        UIAxes_mmode               matlab.ui.control.UIAxes
        GridLayout2                matlab.ui.container.GridLayout
        UIAxes_cwt                 matlab.ui.control.UIAxes
        UIAxes_barker              matlab.ui.control.UIAxes
        UIAxes_amode               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        us_spec;        % ultrasound spesification detail
        data_spec;      % spesification about ultrasound data

        proc_pre;       % consists of signal pre-processing filters
        proc_barker;    % consists of barker code constants

        USData_raw;     % ultrasound data
        USData_barker;  % processed ultrasound data
        USData_env;     % processed ultrasound data
    end
    
    methods (Access = private)
        
        function process_and_display(app, probenumber_to_show, timestamp_to_show)

            % signal processing -------------------------------------------

            % obtain raw data
            S_raw        = app.USData_raw(probenumber_to_show, :, timestamp_to_show) .* app.proc_pre.sigFilt;
            S_corr       = app.USData_barker(probenumber_to_show, :, timestamp_to_show);
            S_env        = app.USData_env(probenumber_to_show, :, timestamp_to_show);
            % cwt in spesific frequency
            %{
            [cfs1, ~]     = cwt(S_raw, app.us_spec.sample_rate, 'FrequencyLimit', [5.3e6, 5.6e6], 'VoicesPerOctave', 48);
            [cfs2, ~]     = cwt(S_raw, app.us_spec.sample_rate, 'FrequencyLimit', [7.0e6, 7.3e6], 'VoicesPerOctave', 48);
            cfs_mag_mean1 = mean(abs(cfs1), 1);
            cfs_mag_mean2 = mean(abs(cfs2), 1);
            cfs_mag_mean  = max([cfs_mag_mean1;cfs_mag_mean2], [], 1);
            %}
            [cfs1, ~]     = cwt(S_raw, app.us_spec.sample_rate, 'FrequencyLimit', [5.3e6, 7.5e6], 'VoicesPerOctave', 48);
            cfs_mag_mean  = mean(abs(cfs1), 1);

            if(app.CheckBox_detectpeak.Value)
                % detect peak
                [peaks_amp, peaks_locs, w, p] = findpeaks( cfs_mag_mean, app.us_spec.sample_rate, ...
                                           'MinPeakHeight', app.Spinner_minpeakheight.Value, ...
                                           'MinPeakProminence', app.Spinner_minpeakprom.Value, ...
                                           'MinPeakDistance', app.Spinner_minpeakdist.Value * 1e-6);
                % eliminate very fat peaks
                peaks_ratio = (p*1e-3) ./ (w*1e6);
                peaks_ratiotresh = 0.2;
                peaks_amp(peaks_ratio < peaks_ratiotresh)  = [];
                peaks_locs(peaks_ratio < peaks_ratiotresh) = [];
                % eliminate echo (if any)
                if(~isempty(peaks_locs))
                    [peak_idx_softtissues, peak_idx_bone, peak_idx_echo] = eliminatePeakEcho( peaks_amp, peaks_locs, ...
                                                                                              app.Spinner_echoratio.Value, ...
                                                                                              app.Spinner_echorange.Value);
                else
                    peak_idx_softtissues = [];
                    peak_idx_bone = [];
                    peak_idx_echo = [];
                end
            end

            % plotting ----------------------------------------------------

            % show m-mode
            display_mmode(app.UIAxes_mmode, app.DropDown_probenumber.Value, app.USData_env, app.data_spec, app.us_spec.t_vector, 20000);
            ylabel(app.UIAxes_mmode, 'Time ($\mu$s)', 'Interpreter', 'Latex');

            % plot mmode
            display_timestamp_mmode(app.UIAxes_mmode, timestamp_to_show);

            % plot amode raw
            delete(findobj(app.UIAxes_amode, 'Tag', 'plot_amoderaw'));
            plot(app.UIAxes_amode, app.us_spec.t_vector, S_raw, '-', 'Color', 'g', 'Tag', 'plot_amoderaw');

            % plot amode barker
            delete(findobj(app.UIAxes_barker, 'Tag', 'plot_amodebarker'));
            plot(app.UIAxes_barker, app.us_spec.t_vector, S_corr, '-', 'Color', 'b', 'Tag', 'plot_amodebarker');
        
            % plot cwt
            delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_cwt'));
            plot(app.UIAxes_cwt, app.us_spec.t_vector, cfs_mag_mean, '-', 'Color', 'r', 'Tag', 'plot_cwt');

            % only draw if user set the detect peak
            if(app.CheckBox_detectpeak.Value)
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
                % only draw if there is any peaks in the variable
                if(~isempty(peak_idx_bone) || ~isempty(peak_idx_softtissues) )
                    hold(app.UIAxes_cwt, 'on');
                    plot(app.UIAxes_cwt, peaks_locs(peak_idx_bone)*1e6,        peaks_amp(peak_idx_bone),        'o', 'Color', 'r', 'MarkerFaceColor', 'r', 'Tag', 'plot_amodepeaks');
                    plot(app.UIAxes_cwt, peaks_locs(peak_idx_softtissues)*1e6, peaks_amp(peak_idx_softtissues), 'o', 'Color', 'r', 'MarkerFaceColor', 'y','Tag', 'plot_amodepeaks');
                    plot(app.UIAxes_cwt, peaks_locs(peak_idx_echo)*1e6,        peaks_amp(peak_idx_echo),        'o', 'Color', 'r', 'MarkerFaceColor', '#EDB120', 'Tag', 'plot_amodepeaks');
                    hold(app.UIAxes_cwt, 'off');
                end
            end

            drawnow limitrate;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path for reading signal
            addpath('functions');
            addpath('functions/displays');
            addpath('functions/signal');
            addpath(genpath('functions/external'));

            % preparing constants for ultrasound spesification
            app.us_spec.v_sound     = 1540; % mm/s
            app.us_spec.sample_rate = 50 * 1e6;
            app.us_spec.period      = 1 / app.us_spec.sample_rate;
            app.us_spec.index2distance_constant  = (1e3 * app.us_spec.v_sound) / (2 * app.us_spec.sample_rate);

            % preparing constant for ultrasound data
            app.data_spec.n_ust     = 30;
            app.data_spec.n_samples = 2500;

            % sigmoid filter to remove initial contact
            sig_halfpoint  = 1; % in microsecond unit
            sig_rate       = 0.15;
            app.proc_pre.sigFilt = sigmoid_simple(app.data_spec.n_samples, sig_halfpoint, sig_rate, app.us_spec.period);

            % path to barkers code
            path_current     = pwd;
            path_barkerscode = 'data';
            text_barkerscode = 'kenans_barkercode.txt';
            app.proc_barker.fullpath_barkerscode = strcat(path_current, filesep, path_barkerscode, filesep, text_barkerscode);
            app.proc_barker.barkerscode  = readmatrix(app.proc_barker.fullpath_barkerscode);
            app.proc_barker.sample_start = app.data_spec.n_samples   - floor( 0.5 * length(app.proc_barker.barkerscode(:, 2)) ) +1;
            app.proc_barker.sample_end   = app.data_spec.n_samples*2 - floor( 0.5 * length(app.proc_barker.barkerscode(:, 2)) );

            % adjust axis
            app.UIAxes_amode.YAxis.Exponent = 3;
            app.UIAxes_barker.YAxis.Exponent = 4;
            app.UIAxes_cwt.YAxis.Exponent = 3;
        end

        % Button pushed function: Button_loaddata
        function Button_loaddataPushed(app, event)
            % get us data
            dname = uigetdir(pwd);

            % read data
            [app.USData_raw, ~, ~] = readTIFF_USsignal(dname, app.data_spec.n_ust, app.data_spec.n_samples);

            % preparing constants for data spesification
            app.data_spec.n_frames  = size(app.USData_raw, 3);

            % add t_vector to our us_spec variable for future usage
            app.us_spec.t_vector    = ( (1:app.data_spec.n_samples)*app.us_spec.period) * 1e6;

            % adjust 
            app.Label_timestamp.Text = sprintf('/ %d', app.data_spec.n_frames);
            app.Slider_timestamp.Limits = [1 app.data_spec.n_frames];
            temp = round(linspace(1, app.data_spec.n_frames, 30));
            % temp = 0:20:app.Slider_timestamp.Limits(2);
            % temp(1) = 1;
            app.Slider_timestamp.MajorTicks = temp;

            % signal processing to get m-mode data
            [app.USData_env, app.USData_barker] = process_USsignal(app.USData_raw, app.data_spec, app.us_spec, app.proc_barker.fullpath_barkerscode);

            % show the data
            probenumber_to_show = app.DropDown_probenumber.Value;
            timestamp_to_show   = round(app.Slider_timestamp.Value);
            process_and_display(app, probenumber_to_show, timestamp_to_show);        
        end

        % Value changed function: DropDown_probenumber
        function DropDown_probenumberValueChanged(app, event)
            probenumber_to_show = app.DropDown_probenumber.Value;
            timestamp_to_show   = round(app.Slider_timestamp.Value);

            % (!) ERROR HANDLING: only execute the display updating code if
            % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData_raw))
                % process the data
                process_and_display(app, probenumber_to_show, timestamp_to_show);
                drawnow;
            end

            %  (!) ERROR HANDLING: this part is only for disabling prev-next
            % probe button, so that it prevents options for user to choose 
            % probe number ouside the range by keep pressing button.
            if (app.DropDown_probenumber.Value == app.DropDown_probenumber.ItemsData(end))
                app.Button_probenext.Enable = false;
                app.Button_probeprev.Enable = true;
            elseif (app.DropDown_probenumber.Value == app.DropDown_probenumber.ItemsData(1))
                app.Button_probenext.Enable = true;
                app.Button_probeprev.Enable = false;
            else
                app.Button_probenext.Enable = true;
                app.Button_probeprev.Enable = true;
            end
        end

        % Button pushed function: Button_probenext
        function Button_probenextPushed(app, event)
            % add the current active transducer
            app.DropDown_probenumber.Value = app.DropDown_probenumber.Value + 1;

            % trigger dropdown value change event
            DropDown_probenumberValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.DropDown_probenumber.Value == app.DropDown_probenumber.ItemsData(end))
                app.Button_probenext.Enable = false;
            else
                app.Button_probeprev.Enable = true;
            end
        end

        % Button pushed function: Button_probeprev
        function Button_probeprevPushed(app, event)
            % add the current active transducer
            app.DropDown_probenumber.Value = app.DropDown_probenumber.Value - 1;

            % trigger dropdown value change event
            DropDown_probenumberValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.DropDown_probenumber.Value == app.DropDown_probenumber.ItemsData(1))
                app.Button_probeprev.Enable = false;
            else
                app.Button_probenext.Enable = true;
            end
        end

        % Value changing function: Slider_timestamp
        function Slider_timestampValueChanging(app, event)
            % show the data
            probenumber_to_show = app.DropDown_probenumber.Value;
            timestamp_to_show   = round(event.Value);
            process_and_display(app, probenumber_to_show, timestamp_to_show);

            % change the edit field for timestamp
            app.EditField_timestamp.Value = timestamp_to_show;

            % avoid user to press 
            if (timestamp_to_show == 1)
                app.Button_timestampnext.Enable = 'on';
                app.Button_timestampprev.Enable = 'off';
            elseif (timestamp_to_show == app.Slider_timestamp.Limits(2))
                app.Button_timestampnext.Enable = 'off';
                app.Button_timestampprev.Enable = 'on';
            else
                app.Button_timestampnext.Enable = 'on';
                app.Button_timestampprev.Enable = 'on';
            end
        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)

            % (!) ERROR HANDLING: only update the A-mode according to 
            % timestamp change only if the user only loaded the raw data
            if (~isempty(app.USData_raw))

                % disable other button that might messed up the play button
                app.Button_play.Enable = 'off';
                app.Button_loaddata.Enable = 'off';
                app.Button_timestampnext.Enable = 'off';
                app.Button_timestampprev.Enable = 'off';
                % turn on pause button so that the user can stop animation
                app.Button_pause.Enable = 'on';
    
                % get the current timestamp and probe number
                timestamp_to_show   = round(app.Slider_timestamp.Value);

                % if the timestamp slider already at the end, automatically
                % return it back to the beginning
                if (timestamp_to_show>=app.Slider_timestamp.Limits(2))
                    timestamp_to_show = 1;
                end

            end

            % loop
            for timestamp = timestamp_to_show : app.data_spec.n_frames

                % i put this here instead of outside the loop, when the
                % user change the probe while in the loop, the probe
                % display will also change
                probenumber_to_show = app.DropDown_probenumber.Value;

                % plot the data
                process_and_display(app, probenumber_to_show, timestamp);

                % set the timestamp slider and edit field according to the loop
                app.Slider_timestamp.Value    = timestamp;
                app.EditField_timestamp.Value = timestamp;
    
                % if user press pause, stop at the last timestamp
                if (app.Button_pause.UserData)
                    % set the user data inside pause button to zero and
                    % disable the pause button
                    app.Button_pause.UserData = 0; 
                    app.Button_pause.Enable = 'off';
                    break;
                end

            % end timeframe loop    
            end

            % set every button to on again
            app.Button_play.Enable = 'on';
            app.Button_loaddata.Enable = 'on';
            % for timestamp next button, only turn on when the timestamp
            % doesn't set at the end
            if(timestamp_to_show ~= app.Slider_timestamp.Limits(2))
                app.Button_timestampnext.Enable = 'on';
            end
            app.Button_timestampprev.Enable = 'on';
            % don't forget to set the pause button to be off
            app.Button_pause.Enable = 'off';

        end

        % Button pushed function: Button_pause
        function Button_pausePushed(app, event)
            app.Button_pause.UserData = 1; 
        end

        % Button pushed function: Button_timestampnext
        function Button_timestampnextPushed(app, event)
            % only can use this button if the data already loaded
            if (~isempty(app.USData_raw))            
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value) + 1;
                process_and_display(app, probenumber_to_show, timestamp_to_show);
    
                % set the time stamp slider and edit field
                app.Slider_timestamp.Value = timestamp_to_show;
                app.EditField_timestamp.Value = timestamp_to_show;
    
                % avoid user to press this button if it is already the
                % end of the timestamp
                if(timestamp_to_show == app.Slider_timestamp.Limits(2))
                    app.Button_timestampnext.Enable = 'off';
                else
                    app.Button_timestampprev.Enable = 'on';
                end    
            end
        end

        % Button pushed function: Button_timestampprev
        function Button_timestampprevPushed(app, event)
            % only can use this button if the data already loaded
            if (~isempty(app.USData_raw))            
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value) - 1;
                process_and_display(app, probenumber_to_show, timestamp_to_show);
    
                % set the time stamp slider and edit field
                app.Slider_timestamp.Value = timestamp_to_show;
                app.EditField_timestamp.Value = timestamp_to_show;

                % avoid user to press this button if it is already the
                % beginning of the timestamp
                if(timestamp_to_show == 1)
                    app.Button_timestampprev.Enable = 'off';
                else
                    app.Button_timestampnext.Enable = 'on';
                end
            end
        end

        % Value changed function: CheckBox_detectpeak
        function CheckBox_detectpeakValueChanged(app, event)
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end

        % Value changed function: Spinner_minpeakheight
        function Spinner_minpeakheightValueChanged(app, event)
            % display only if user set the detect peak check box
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end

        % Value changed function: Spinner_minpeakprom
        function Spinner_minpeakpromValueChanged(app, event)
            % display only if user set the detect peak check box
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end

        % Value changed function: Spinner_minpeakdist
        function Spinner_minpeakdistValueChanged(app, event)
            % display only if user set the detect peak check box
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end

        % Value changed function: Spinner_echoratio
        function Spinner_echoratioValueChanged(app, event)
            % display only if user set the detect peak check box
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end

        % Value changed function: Spinner_echorange
        function Spinner_echorangeValueChanged(app, event)
            % display only if user set the detect peak check box
            if(app.CheckBox_detectpeak.Value)
                % show the data
                probenumber_to_show = app.DropDown_probenumber.Value;
                timestamp_to_show   = round(app.Slider_timestamp.Value);
                process_and_display(app, probenumber_to_show, timestamp_to_show);
            else
                delete(findobj(app.UIAxes_cwt, 'Tag', 'plot_amodepeaks'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes_amode = uiaxes(app.GridLayout2);
            title(app.UIAxes_amode, 'A-mode Raw Signal')
            xlabel(app.UIAxes_amode, 'Time (μs)')
            ylabel(app.UIAxes_amode, 'Amplitude')
            zlabel(app.UIAxes_amode, 'Z')
            app.UIAxes_amode.YLim = [-5000 5000];
            app.UIAxes_amode.YTick = [-5000 -2500 0 2500 5000];
            app.UIAxes_barker = uiaxes(app.GridLayout2);
            title(app.UIAxes_barker, 'A-mode Correlated with Barker Code')
            xlabel(app.UIAxes_barker, 'Time (μs)')
            ylabel(app.UIAxes_barker, 'Amplitude')
            zlabel(app.UIAxes_barker, 'Z')
            app.UIAxes_barker.YLim = [-20000 20000];
            app.UIAxes_cwt = uiaxes(app.GridLayout2);
            title(app.UIAxes_cwt, '(Continuous) Wavelet Transform at 7.0 - 7.3 MHz')
            xlabel(app.UIAxes_cwt, 'Time (μs)')
            ylabel(app.UIAxes_cwt, 'Coeffs Magnitude')
            zlabel(app.UIAxes_cwt, 'Z')
            app.UIAxes_cwt.YLim = [0 2500];
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UIAxes_mmode = uiaxes(app.GridLayout5);
            title(app.UIAxes_mmode, 'M-mode Image')
            xlabel(app.UIAxes_mmode, 'Timeframe')
            ylabel(app.UIAxes_mmode, 'Time (μs)')
            zlabel(app.UIAxes_mmode, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_timestamp = uilabel(app.GridLayout6);
            app.Label_timestamp.Text = '/ 1000';
            app.Button_pause = uibutton(app.GridLayout6, 'push');
            app.Button_pause.ButtonPushedFcn = createCallbackFcn(app, @Button_pausePushed, true);
            app.Button_pause.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'pause.png');
            app.Button_pause.Text = '';
            app.Button_play = uibutton(app.GridLayout6, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.png');
            app.Button_play.Text = '';
            app.Button_loaddata = uibutton(app.GridLayout6, 'push');
            app.Button_loaddata.ButtonPushedFcn = createCallbackFcn(app, @Button_loaddataPushed, true);
            app.Button_loaddata.Text = 'Browse';
            app.DropDown_probenumber = uidropdown(app.GridLayout6);
            app.DropDown_probenumber.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.DropDown_probenumber.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.DropDown_probenumber.ValueChangedFcn = createCallbackFcn(app, @DropDown_probenumberValueChanged, true);
            app.DropDown_probenumber.Value = 30;
            app.Button_probeprev = uibutton(app.GridLayout6, 'push');
            app.Button_probeprev.ButtonPushedFcn = createCallbackFcn(app, @Button_probeprevPushed, true);
            app.Button_probeprev.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-left.png');
            app.Button_probeprev.Text = '';
            app.Button_probenext = uibutton(app.GridLayout6, 'push');
            app.Button_probenext.ButtonPushedFcn = createCallbackFcn(app, @Button_probenextPushed, true);
            app.Button_probenext.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-right.png');
            app.Button_probenext.Enable = 'off';
            app.Button_probenext.Text = '';
            app.EditField_timestamp = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_timestamp.ValueDisplayFormat = '%d';
            app.Button_timestampnext = uibutton(app.GridLayout6, 'push');
            app.Button_timestampnext.ButtonPushedFcn = createCallbackFcn(app, @Button_timestampnextPushed, true);
            app.Button_timestampnext.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'plus-small.png');
            app.Button_timestampnext.Text = '';
            app.Button_timestampprev = uibutton(app.GridLayout6, 'push');
            app.Button_timestampprev.ButtonPushedFcn = createCallbackFcn(app, @Button_timestampprevPushed, true);
            app.Button_timestampprev.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'minus-small.png');
            app.Button_timestampprev.Enable = 'off';
            app.Button_timestampprev.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Slider_timestamp = uislider(app.GridLayout7);
            app.Slider_timestamp.Limits = [1 100];
            app.Slider_timestamp.ValueChangingFcn = createCallbackFcn(app, @Slider_timestampValueChanging, true);
            app.Slider_timestamp.MinorTicks = [];
            app.Slider_timestamp.Value = 1;
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.MinPeakHeightSpinnerLabel = uilabel(app.GridLayout8);
            app.MinPeakHeightSpinnerLabel.Text = 'Min Peak Height';
            app.MinPeakPromSpinnerLabel = uilabel(app.GridLayout8);
            app.MinPeakPromSpinnerLabel.Text = 'Min Peak Prom.';
            app.Spinner_minpeakprom = uispinner(app.GridLayout8);
            app.Spinner_minpeakprom.Step = 5;
            app.Spinner_minpeakprom.Limits = [0 500];
            app.Spinner_minpeakprom.ValueChangedFcn = createCallbackFcn(app, @Spinner_minpeakpromValueChanged, true);
            app.Spinner_minpeakprom.Value = 120;
            app.MinPeakDistSpinnerLabel = uilabel(app.GridLayout8);
            app.MinPeakDistSpinnerLabel.Text = 'Min Peak Dist.';
            app.Spinner_minpeakdist = uispinner(app.GridLayout8);
            app.Spinner_minpeakdist.Step = 0.1;
            app.Spinner_minpeakdist.Limits = [0 3];
            app.Spinner_minpeakdist.ValueChangedFcn = createCallbackFcn(app, @Spinner_minpeakdistValueChanged, true);
            app.Spinner_minpeakdist.Value = 1.8;
            app.Spinner_minpeakheight = uispinner(app.GridLayout8);
            app.Spinner_minpeakheight.Step = 5;
            app.Spinner_minpeakheight.Limits = [0 500];
            app.Spinner_minpeakheight.ValueChangedFcn = createCallbackFcn(app, @Spinner_minpeakheightValueChanged, true);
            app.Spinner_minpeakheight.Value = 100;
            app.EchoRatioSpinnerLabel = uilabel(app.GridLayout8);
            app.EchoRatioSpinnerLabel.Text = 'Echo Ratio';
            app.Spinner_echoratio = uispinner(app.GridLayout8);
            app.Spinner_echoratio.Step = 0.1;
            app.Spinner_echoratio.Limits = [0 1];
            app.Spinner_echoratio.ValueChangedFcn = createCallbackFcn(app, @Spinner_echoratioValueChanged, true);
            app.Spinner_echoratio.Value = 0.15;
            app.EchoRangeSpinnerLabel = uilabel(app.GridLayout8);
            app.EchoRangeSpinnerLabel.Text = 'Echo Range';
            app.Spinner_echorange = uispinner(app.GridLayout8);
            app.Spinner_echorange.Step = 0.1;
            app.Spinner_echorange.Limits = [0 2];
            app.Spinner_echorange.ValueChangedFcn = createCallbackFcn(app, @Spinner_echorangeValueChanged, true);
            app.Spinner_echorange.Value = 0.75;
            app.CheckBox_detectpeak = uicheckbox(app.GridLayout8);
            app.CheckBox_detectpeak.ValueChangedFcn = createCallbackFcn(app, @CheckBox_detectpeakValueChanged, true);
            app.CheckBox_detectpeak.Text = 'Detect Peak';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = amode_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end