classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        VersionMenu                     matlab.ui.container.Menu
        LogsTextArea                    matlab.ui.control.TextArea
        LogsTextAreaLabel               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        UltrasoundNewTab                matlab.ui.container.Tab
        USTimestampSlider_2             matlab.ui.control.Slider
        USTimestampEditField_2          matlab.ui.control.NumericEditField
        USTimestampMaxLabel_2           matlab.ui.control.Label
        USPauseButton_2                 matlab.ui.control.Button
        USPlayButton_2                  matlab.ui.control.Button
        ExportProbeConfigButton_2       matlab.ui.control.Button
        DetectDepthButton_2             matlab.ui.control.Button
        SourceSignalPanel               matlab.ui.container.Panel
        USFilesProcessButton            matlab.ui.control.Button
        USFilesLoadButton               matlab.ui.control.Button
        USFilesEditField                matlab.ui.control.EditField
        USFilesEditFieldLabel           matlab.ui.control.Label
        WindowConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        WindowConfRadioButton           matlab.ui.control.RadioButton
        WindowCalibRadioButton          matlab.ui.control.RadioButton
        WindowNoneRadioButton           matlab.ui.control.RadioButton
        WindowConfEditField             matlab.ui.control.EditField
        WindowConfButton                matlab.ui.control.Button
        WindowCalibEditField            matlab.ui.control.EditField
        WindowCalibButton               matlab.ui.control.Button
        NavigationPanel                 matlab.ui.container.Panel
        ProbeNumDropDown                matlab.ui.control.DropDown
        ProbenumDropDownLabel           matlab.ui.control.Label
        ProbeNumNextButton              matlab.ui.control.Button
        ProbeNumPrevButton              matlab.ui.control.Button
        WindowAdjustmentPanel           matlab.ui.container.Panel
        USWindowConfUITable             matlab.ui.control.Table
        USWindowUBSlider                matlab.ui.control.Slider
        WindowUpperBoundmmSliderLabel_2  matlab.ui.control.Label
        USWindowUBEditField             matlab.ui.control.EditField
        USWindowLBSlider                matlab.ui.control.Slider
        WindowLowerBoundmmSliderLabel_2  matlab.ui.control.Label
        USWindowLBEditField             matlab.ui.control.EditField
        USMmodeSignalAxes               matlab.ui.control.UIAxes
        USAmodeSignalAxes               matlab.ui.control.UIAxes
        UltrasoundTab                   matlab.ui.container.Tab
        USTimestampEditField            matlab.ui.control.NumericEditField
        USTimestampMaxLabel             matlab.ui.control.Label
        USWindowUITable                 matlab.ui.control.Table
        UltrasoundDataPanel             matlab.ui.container.Panel
        ProcessUSStatusLamp             matlab.ui.control.Lamp
        ProcessUSButton                 matlab.ui.control.Button
        LoadUSStatusLamp                matlab.ui.control.Lamp
        LoadUSDataButton                matlab.ui.control.Button
        INIFileEditField                matlab.ui.control.EditField
        INIFileEditFieldLabel           matlab.ui.control.Label
        INIFileBrowseButton             matlab.ui.control.Button
        TIFFPathEditField               matlab.ui.control.EditField
        TIFFPathEditFieldLabel          matlab.ui.control.Label
        TIFFPathBrowseButton            matlab.ui.control.Button
        WindowPanel                     matlab.ui.container.Panel
        ProbeNextButton                 matlab.ui.control.Button
        ProbePrevButton                 matlab.ui.control.Button
        ProbeNumberDropDown             matlab.ui.control.DropDown
        ProbeNumberDropDownLabel        matlab.ui.control.Label
        WindowLBSlider                  matlab.ui.control.Slider
        WindowLBEditField               matlab.ui.control.EditField
        WindowUBSlider                  matlab.ui.control.Slider
        WindowUpperBoundmmSliderLabel   matlab.ui.control.Label
        WindowUBEditField               matlab.ui.control.EditField
        WindowLowerBoundmmSliderLabel   matlab.ui.control.Label
        ExportProbeConfigButton         matlab.ui.control.Button
        USPauseButton                   matlab.ui.control.Button
        USPlayButton                    matlab.ui.control.Button
        DetectDepthButton               matlab.ui.control.Button
        USTimestampSlider               matlab.ui.control.Slider
        UltrasoundTimestampSliderLabel  matlab.ui.control.Label
        USMmodeAxes                     matlab.ui.control.UIAxes
        USAmodeAxes                     matlab.ui.control.UIAxes
        MocapTab                        matlab.ui.container.Tab
        DisplayOptionsPanel             matlab.ui.container.Panel
        TrajectoryLabel                 matlab.ui.control.Label
        LoadDisplayOptionButton         matlab.ui.control.Button
        MocapPauseButton                matlab.ui.control.Button
        MocapPlayButton                 matlab.ui.control.Button
        EndUSTDropDown                  matlab.ui.control.DropDown
        EndDropDownLabel                matlab.ui.control.Label
        StartUSTDropDown                matlab.ui.control.DropDown
        StartDropDownLabel              matlab.ui.control.Label
        TrajectoryNumberEditField       matlab.ui.control.NumericEditField
        TrajectorySwitch                matlab.ui.control.Switch
        AmodeSwitch                     matlab.ui.control.Switch
        AmodeSwitchLabel                matlab.ui.control.Label
        USTipsSwitch                    matlab.ui.control.Switch
        USTipsSwitchLabel               matlab.ui.control.Label
        MarkersSwitch                   matlab.ui.control.Switch
        MarkersSwitchLabel              matlab.ui.control.Label
        MocapDataPanel                  matlab.ui.container.Panel
        SynchronizeUSMocapStatusLamp    matlab.ui.control.Lamp
        SynchronizeUSMocapButton        matlab.ui.control.Button
        LoadMocapStatusLamp             matlab.ui.control.Lamp
        LoadMocapDataButton             matlab.ui.control.Button
        HolderCalibBrowseButton         matlab.ui.control.Button
        HolderCalibEditField            matlab.ui.control.EditField
        HolderCalibEditFieldLabel       matlab.ui.control.Label
        MATQualisysEditField            matlab.ui.control.EditField
        MATQualisysEditFieldLabel       matlab.ui.control.Label
        TRCQualisysEditField            matlab.ui.control.EditField
        TRCQualisysEditFieldLabel       matlab.ui.control.Label
        TRCLocalEditFieldLabel          matlab.ui.control.Label
        TRCLocalEditField               matlab.ui.control.EditField
        MatQualisysBrowseButton         matlab.ui.control.Button
        TRCQualisysPathBrowseButton     matlab.ui.control.Button
        TRCLocalPathBrowseButton        matlab.ui.control.Button
        MocapTimestampSlider            matlab.ui.control.Slider
        MocapTimestampSliderLabel       matlab.ui.control.Label
        UIAxesMocap                     matlab.ui.control.UIAxes
        RegistrationTab                 matlab.ui.container.Tab
        KinematicTab                    matlab.ui.container.Tab
    end

    properties (Access = private)

        % APPLICATION VARIABLES -------------------------------------------
        SettingsApp;

        % GENERAL PRIVATE VARIABLES ---------------------------------------

        % constant for lamp color
        STATUS_DEFAULT = 1;
        STATUS_SUCCESS = 2;
        STATUS_WARNING = 3;
        STATUS_ERROR   = 4;

        % constant for message log
        MESSAGE_DEFAULT = 1;
        MESSAGE_RUNNING = 2;
        MESSAGE_WARNING = 3;

        % constant for ultrasound display
        DISPLAY_US_A_Raw     = [1 0 0 0];
        DISPLAY_US_A_Proc    = [2 0 0 0];
        DISPLAY_US_AM_Window = [2 1 1 0];
        DISPLAY_US_AM_Time   = [2 0 1 0];
        DISPLAY_US_AM_Depth  = [3 0 1 0];
        DISPLAY_US_AM_All    = [3 1 1 1];

        % variable for animation
        is_animationUS_playing      = false;
        is_animationMocap_playing   = false;

        % ULTRASOUND RELATED PRIVATE VARIABLES ----------------------------

        % ultrasound configuration data
        ust_config                  = [];
        ust_struct                  = [];
        % ultrasound data
        USData                      = [];
        USData_processedsignal      = [];
        USData_allpeaks             = [];
        USData_aftersync            = [];
        timestamps_USData           = [];
        timestamps_USData_aftersync = [];

        % ultrasound data spec
        data_spec = [];
        % ultrasound spec 
        us_spec   = [];
        % ultrasound usable data
        usable_timestamp = [];

        % display
        mmode_display_threshold = 15000;

        % flags
        % ultrasound window option
        windowoption_selected = 1;
        windowoption_loaded   = 1;

        % MOCAP RELATED PRIVATE VARIABLES ---------------------------------

        % quaisys trc files data
        markerdata_mypc         = [];
        markerdata_qualisys     = [];
        matqtm_postprocess      = [];
        calibration_markerstick = [];

        % transformations
        T_global_markerstick    = [];
        T_markerstick_usttip    = [];

        % point clouds
        pc_amode  = [];
        pc_usttip = [];

        % display
        basevector_Rcorrection    = eul2rotm([0 0 deg2rad(90)]);
        offset_ustext             = 5;
    end

    methods (Access = private)

        % This is a function for message log ------------------------------
        function addLog(app, message_type, logstr)
            % make a header to separate between type of logs
            switch message_type
                case app.MESSAGE_DEFAULT
                    header = '$ ';
                case app.MESSAGE_RUNNING
                    header = '>>';
                case app.MESSAGE_WARNING
                    header = '!!';
                otherwise
                    header = '$ ';
            end            
            current_log = sprintf('%s %s', header, logstr);

            % display the log
            if(isempty(app.LogsTextArea.Value{1}))
                app.LogsTextArea.Value = current_log;
            else
                app.LogsTextArea.Value = [ string(app.LogsTextArea.Value); current_log ];
            end
            scroll(app.LogsTextArea, 'bottom');      
            drawnow;  
        end
    
        % this is a function for status lamps in GUI ----------------------
        function changeLampStatus(app, lampobj, status)
            % change the lamp status color based on the message
            switch status
                case app.STATUS_DEFAULT
                    lampobj.Color = [0.80, 0.80, 0.80];
                case app.STATUS_SUCCESS
                    lampobj.Color = [0.00, 1.00, 0.00];
                case app.STATUS_WARNING
                    lampobj.Color = [1.00, 1.00, 0.00];
                case app.STATUS_ERROR
                    lampobj.Color = [1.00, 0.00, 0.00];
                otherwise
                    lampobj.Color = [0.80, 0.80, 0.80];
            end
            lampobj.UserData = status;
        end
        
        % function to control the ultrasound display ----------------------
        function updateUSDisplays(app, display_mode, probeNumber_toShow, timestamp_toshow)

            if(display_mode(1))
                % amode raw
                if(display_mode(1)==1)
                    display_amode( app.USAmodeAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData, ...
                                   [], ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                % amode processed envelope
                elseif (display_mode(1)>=2)
                    display_amode( app.USAmodeAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData_processedsignal.correlated, ...
                                   app.USData_processedsignal.envelope, ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                    % if peak already detected
                    if (display_mode(1)==3)
                        %show peaks on processed a-mode
                        display_peak_amode(app.USAmodeAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');
                    end
                end

                % amode window
                display_signalwindow_amode( app.USAmodeAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_amode_windowlowerbound', ...
                                            'plot_amode_windowupperbound');
            end
            
            if(display_mode(2))
                % show m-mode
                display_mmode(app.USMmodeAxes, ...
                              probeNumber_toShow, ...
                              app.USData_processedsignal.envelope, ...
                              app.data_spec, ...
                              app.us_spec.x_axis_values, ...
                              app.mmode_display_threshold);
                
                % draw window on mmode
                display_signalwindow_mmode( app.USMmodeAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_mmode_windowlowerbound', ...
                                            'plot_mmode_windowupperbound');
            end

            % show mmode timestamp
            if(display_mode(3))
                % display timestamp for m-mode
                display_timestamp_mmode(app.USAModeAxes, timestamp_toshow);   
            end

            % show peaks
            if(display_mode(4))
                 % plot the peaks in the m-mode axes
                display_peak_mmode(app.USMmodeAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
            end
            
        end

            if(display_mode(1))
                % amode raw
                if(display_mode(1)==1)
                    display_amode( app.USAmodeSignalAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData, ...
                                   [], ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                % amode processed envelope
                elseif (display_mode(1)>=2)
                    display_amode( app.USAmodeSignalAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData_processedsignal.correlated, ...
                                   app.USData_processedsignal.envelope, ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                    % if peak already detected
                    if (display_mode(1)==3)
                        %show peaks on processed a-mode
                        display_peak_amode(app.USAmodeSignalAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');
                    end
                end
            end
            
            if(display_mode(2))
                % show m-mode
                display_mmode(app.USMmodeSignalAxes, ...
                              probeNumber_toShow, ...
                              app.USData_processedsignal.envelope, ...
                              app.data_spec, ...
                              app.us_spec.x_axis_values, ...
                              app.mmode_display_threshold);
            end

            % show mmode timestamp
            if(display_mode(3))
                % display timestamp for m-mode
                display_timestamp_mmode(app.USMmodeSignalAxes, timestamp_toshow);   
            end

            % show peaks
            if(display_mode(4))
                 % plot the peaks in the m-mode axes
                 % display_peak_mmode(app.USMmodeSignalAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
            end
        end
        
        function updateUSDisplays3(app, display_mode, probeNumber_toShow, timestamp_toshow)

            % for A-Mode Axes (raw)
            if(display_mode(1))

                % if 1, only raw a-mode
                if(display_mode(1)>=1)
                    display_amode( app.USAmodeSignalAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData, ...
                                   [], ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                end

                % if 2, raw a-mode and window
                if(display_mode(1)>=2)
                    % amode window
                    display_signalwindow_amode( app.USAmodeSignalAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_amode_windowlowerbound', ...
                                                'plot_amode_windowupperbound');
                end
            end


            % for A-Mode Axes (processed)
            if (display_mode(2))

                % if 1, only processed a-mode
                if(display_mode(2)>=1)
                    display_amode( app.USAmodeSignalAxes, ...
                                   'distance', ...
                                   probeNumber_toShow, ...
                                   timestamp_toshow, ...
                                   app.USData_processedsignal.correlated, ...
                                   app.USData_processedsignal.envelope, ...
                                   app.us_spec.x_axis_values , ...
                                   'plot_raw1', ...
                                   'plot_env1');
                end

                % if 2, processed a-mode and window
                if(display_mode(2)>=2)
                    % amode window
                    display_signalwindow_amode( app.USAmodeSignalAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_amode_windowlowerbound', ...
                                                'plot_amode_windowupperbound');
                end

                % if 3, processed a-mode, window, and peak
                if(display_mode(2)>=3)
                    display_peak_amode(app.USAmodeSignalAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');
                end

            end

            % for M-Mode images
            if (display_mode(3))

                % if 1, only M-mode image
                if (display_mode(3)>=1)
                    display_mmode(app.USMmodeSignalAxes, ...
                                  probeNumber_toShow, ...
                                  app.USData_processedsignal.envelope, ...
                                  app.data_spec, ...
                                  app.us_spec.x_axis_values, ...
                                  app.mmode_display_threshold);
                end

                % if 2, M-mode image and the window
                if (display_mode(3)>=2)
                    display_signalwindow_mmode( app.USMmodeSignalAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_mmode_windowlowerbound', ...
                                                'plot_mmode_windowupperbound');
                end

                if (display_mode(3)>=3)
                    display_usabledata_mmode( app.USMmodeSignalAxes, ...
                                              app.data_spec.n_frames, ...
                                              app.us_spec.x_axis_values, ...
                                              app.usable_timestamp, ...
                                              'plot_mmode_usabledata');
                end
            end

            % for M-Mode timestamp (separated from the if before, because
            % when we draw timestamp we don't need to redraw the M-mode)
            if (display_mode(4))
                display_timestamp_mmode(app.USMmodeSignalAxes, timestamp_toshow);   
            end

            % for M-Mode peals (separated from the if before, because
            % when we draw peaks we don't need to redraw the M-mode)
            if (display_mode(5))
                display_peak_mmode(app.USMmodeSignalAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
            end
            
        end
    end
    
    methods (Access = public)
        
        % this function is for updating variables from settings app -------
        function updateSettings(app, settings_struct)
            
            % get the values
            app.us_spec.v_sound     = settings_struct.v_sound; % m/s
            app.us_spec.sample_rate = settings_struct.sample_rate * 1e6; %Hz
            app.us_spec.period      = 1/(app.us_spec.sample_rate); %s
            app.us_spec.index2distance_constant = 1e3 * app.us_spec.v_sound / (2 * app.us_spec.sample_rate);

            app.mmode_display_threshold = settings_struct.mmode_display_threshold;

            % warn the user to reload and reprocess again
            if(~isempty(app.USData))
                changeLampStatus(app, app.LoadUSStatusLamp, app.STATUS_WARNING);
            end
            if(~isempty(app.USData_processedsignal))
                changeLampStatus(app, app.ProcessUSStatusLamp, app.STATUS_WARNING);
            end

            addLog(app, app.MESSAGE_DEFAULT, 'Setting is changed.')
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions\'));

            % declare a default settings for ultrasound
            app.us_spec.v_sound                 = 1540; % m/s
            app.us_spec.sample_rate             = 50 * 1e6; %Hz
            app.us_spec.period                  = 1/(app.us_spec.sample_rate); %s
            app.us_spec.index2distance_constant = 1e3 * app.us_spec.v_sound / (2 * app.us_spec.sample_rate); % mm
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            settings_struct.v_sound     = app.us_spec.v_sound; % m/s
            settings_struct.sample_rate = app.us_spec.sample_rate * 1e-6;  % for readability for user, i use MHz for settings

            settings_struct.mmode_display_threshold = app.mmode_display_threshold;
            
            % open the settings
            app.SettingsApp = settingsapp(app, settings_struct);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            % check if there is animation playing and to prevent the user
            % to not going to the other tab
            if (app.is_animationUS_playing)
                uialert(app.UIFigure, ['Ultrasound animation is now playing. ' ...
                                       'If the tab changed, it might affects the process. ' ...
                                       'Please stop the animation first.'], 'Warning', 'Icon','warning');
                % bring back the user to go to ultrasound tab
                app.TabGroup.SelectedTab = app.UltrasoundTab;
            end

            
        end

        % Button pushed function: TIFFPathBrowseButton
        function TIFFPathBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to TIFF files: Ultrasound raw signal');

            % get the directory
            directory_toUSData = uigetdir('data\');
            % show the directory to editfield if the user click open
            if(directory_toUSData~=0)
                app.TIFFPathEditField.Value = directory_toUSData;
                addLog(app, app.MESSAGE_DEFAULT, sprintf('Path to TIFF file specified as: "%s"', directory_toUSData));

                % the user already loaded the data, give them a warning sign
                % that they need to re-load the data again.
                if(~isempty(app.USData))
                    changeLampStatus(app, app.LoadUSStatusLamp, app.STATUS_WARNING);
                end
                if(~isempty(app.USData_processedsignal) )
                    changeLampStatus(app, app.LoadUSStatusLamp, app.STATUS_WARNING);
                end
            end

        end

        % Button pushed function: INIFileBrowseButton
        function INIFileBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to INI file: Ultrasound configuration file');

            % get the directory
            [file_mypc, path_mypc] = uigetfile({'data\*.ini'});
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.INIFileEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('Ultrasound configuration file specified as: "%s"', app.INIFileEditField.Value));
            end
        end

        % Button pushed function: LoadUSDataButton
        function LoadUSDataButtonPushed(app, event)
            %  (!) ERROR HANDLING: if user still not specified the path, 
            % give error message
            if( isempty(app.TIFFPathEditField.Value) || ...
                isempty(app.INIFileEditField.Value) )

                uialert(app.UIFigure, 'Some of the path to required files are still empty', 'Empty Fields', 'Icon', 'error');
                changeLampStatus(app, app.LoadUSStatusLamp, app.STATUS_ERROR);
                return
            end


            % ============================================================
            % READING DATA
            % ============================================================

            % read US data
            changeLampStatus(app, app.LoadUSStatusLamp, app.STATUS_WARNING);

            addLog(app, app.MESSAGE_RUNNING, 'Reading ultrasound signal data...');
            [app.USData, app.timestamps_USData, ~] = readTIFF_USsignal(app.TIFFPathEditField.Value, 30, 2500);
            % obtain ultrasound settings, we need window information for peak
            % detection, and we also need several other information for the next phases
            addLog(app, app.MESSAGE_RUNNING, 'Reading ultrasound configuration data...');
            app.ust_struct = ini2struct(app.INIFileEditField.Value);

            % preparing data_spec structs which needed by the peak detection algorithm
            app.data_spec.n_ust     = size(app.USData, 1);
            app.data_spec.n_samples = size(app.USData, 2);
            app.data_spec.n_frames  = size(app.USData, 3);
            % preparing us_spec structs which needed by the peak detection algorithm
            app.us_spec.x_axis_values = (1:app.data_spec.n_samples) .* app.us_spec.index2distance_constant; % mm
            app.us_spec.t_vector      = ((1:app.data_spec.n_samples) .* app.us_spec.period) * 1e6; % mu s

            % initialize a table for containing the probe properties
            app.ust_config = table( 'Size', [1,3], ...
                                'VariableTypes', ["string", "double", "double"], ...
                                'VariableNames', ["Group", "WindowLowerBound", "WindowUpperBound"]);
            % get the field names
            ust_struct_fieldnames = fieldnames(app.ust_struct);
            % loop through all the fields
            for i=1:app.data_spec.n_ust
                % get lower and upper bound data
                lowerbound_str = app.ust_struct.(ust_struct_fieldnames{i}).LowerBound;
                upperbound_str = app.ust_struct.(ust_struct_fieldnames{i}).UpperBound;
                group_str      = app.ust_struct.(ust_struct_fieldnames{i}).Group;
                
                % but because it is string, and the US machine somehow uses comma 
                % separator for floating point, so we need to replace the comma as 
                % point first, then convert it to double.
                app.ust_config.WindowLowerBound(i) = str2double(strrep(lowerbound_str, ',', '.'));
                app.ust_config.WindowUpperBound(i) = str2double(strrep(upperbound_str, ',', '.'));
                % remove double quote in the group string from ini fle
                app.ust_config.Group(i) = strrep(group_str, '"', '');
            end


            % ============================================================
            % CONFIGURE THE INTERFACE
            % ============================================================

            probeNumber_toShow = app.ProbeNumberDropDown.Value;

            % set the timestamp slider to the same amount of USData frames
            app.USTimestampSlider.Limits = [1 app.data_spec.n_frames];
            % set the timeframe maximum label to maximum frame we have
            app.USTimestampMaxLabel.Text = sprintf('/ %s', num2str(app.data_spec.n_frames));

            % set the window sliders and edit field according to data
            app.WindowLBSlider.Value    = app.ust_config.WindowLowerBound(probeNumber_toShow);
            app.WindowUBSlider.Value    = app.ust_config.WindowUpperBound(probeNumber_toShow);
            app.WindowLBEditField.Value = num2str(app.ust_config.WindowLowerBound(probeNumber_toShow));
            app.WindowUBEditField.Value = num2str(app.ust_config.WindowUpperBound(probeNumber_toShow));
            app.WindowLBSlider.Limits   = [app.us_spec.x_axis_values(1) app.us_spec.x_axis_values(end)];
            app.WindowUBSlider.Limits   = [app.us_spec.x_axis_values(1) app.us_spec.x_axis_values(end)];

            % set the uitable according to the data
            app.USWindowUITable.Data = [app.ust_config.WindowLowerBound, app.ust_config.WindowUpperBound];


            % ============================================================
            % SHOW AMODE DATA
            % ============================================================

            % reset the axes
            cla(app.USAmodeAxes);
            cla(app.USMmodeAxes);

            % show the data
            timestamp_toshow   = round(app.USTimestampSlider.Value);
            updateUSDisplays(app, app.DISPLAY_US_A_Raw, probeNumber_toShow, timestamp_toshow);

            % reset the processed and depth data
            if(~isempty(app.USData_allpeaks))
                app.USData_allpeaks = [];
            end
            if(~isempty(app.USData_processedsignal))
                app.USData_processedsignal = [];
            end

            % ============================================================
            % INDICATORS
            % ============================================================

            % give message to user
            addLog(app, app.MESSAGE_DEFAULT, 'Finished reading Ultrasound Data.');       
        end

        % Button pushed function: ProcessUSButton
        function ProcessUSButtonPushed(app, event)
            % SUMMARY OF FUNCTION:
            % As the name suggests, this function will handle the digital 
            % signal processing of the ultrasound raw data. The depth is
            % not detected by this function yet
            
            % (!) ERROR HANDLING: check if user already load the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData))
                uialert(app.UIFigure, 'Ultrasound Data is not loaded yet. Please load from the Ultrasound Tab', 'File not found', 'Icon', 'error');
                changeLampStatus(app, app.ProcessUSStatusLamp, app.STATUS_ERROR);
                return
            end
            % (!) ERROR HANDLING: check if load data needs to be redone,
            % this is usually occur when: user change the ultrasound
            % settingsin the middle of using the software
            if(app.LoadUSStatusLamp.UserData == app.STATUS_WARNING)
                messagestr = {'Please reload the ultrasound data first. This can be occured because either: ', ...
                              '1) You changed the settings', ...
                              '2) You changed the path to your data', ...
                              '3) Magic'};
                uialert(app.UIFigure, messagestr, 'Warning', 'Icon', 'warning');
                changeLampStatus(app, app.ProcessUSStatusLamp, app.STATUS_ERROR);
                return
            end

            % read US data
            changeLampStatus(app, app.ProcessUSStatusLamp, app.STATUS_WARNING);
            addLog(app, app.MESSAGE_RUNNING, 'Processing ultrasound signal data...');
            
            % signal processing
            path_barkercode = strcat('data', filesep, 'kenans_barkercode.txt');
            [app.USData_processedsignal.envelope, app.USData_processedsignal.correlated] = process_USsignal(app.USData, app.data_spec, app.us_spec, path_barkercode);
            
            % gather necessary data we want to show
            probeNumber_toShow = app.ProbeNumberDropDown.Value;
            timestamp_toshow   = round(app.USTimestampSlider.Value);

            % update all the ultrasound related displays
            updateUSDisplays(app, app.DISPLAY_US_AM_Window, probeNumber_toShow, timestamp_toshow);

            % give indicators to user
            changeLampStatus(app, app.ProcessUSStatusLamp, app.STATUS_SUCCESS);
            addLog(app, app.MESSAGE_DEFAULT, 'Finished processing ultrasound signal data...');
        end

        % Value changing function: WindowLBSlider
        function WindowLBSliderValueChanging(app, event)
            LB_value = event.Value;

            % to prevent stupidity of the user which maybe will specify 
            % Lower Bound (LB) to be higher than Upper Bound (UB), we need 
            % to move the UB so that the LB will always be lower
            UB_value = app.WindowUBSlider.Value;
            if(LB_value >= UB_value - 0.5)
                UB_value = LB_value + 0.5;
                
                % if UB reaches slider limit, we clamp it to the limit
                if (UB_value > app.WindowUBSlider.Limits(2))
                    LB_value = app.WindowUBSlider.Limits(2) - 0.5;
                    UB_value = app.WindowUBSlider.Limits(2);
                end

                app.WindowUBSlider.Value = UB_value;
            end

            % (!) ERROR HANDLING: only execute the display updating code if
            % % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData))

                % get the current active probe number
                probeNumber_toShow  = app.ProbeNumberDropDown.Value;
                % update the window lowerbound within ust_config for this
                % spesific probe number
                app.ust_config.WindowLowerBound(probeNumber_toShow) = LB_value;
                app.ust_config.WindowUpperBound(probeNumber_toShow) = UB_value;
                % update also the edit field so user can see the number
                app.WindowLBEditField.Value = num2str(LB_value);
                app.WindowUBEditField.Value = num2str(UB_value);
    
                % draw window on amode
                display_signalwindow_amode( app.USAmodeAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_amode_windowlowerbound', ...
                                            'plot_amode_windowupperbound');

                % (!) ERROR HANDLING: only execute the mmode update if the
                % user already process the signal
                if(~isempty(app.USData_processedsignal))
                    % draw window on mmode
                    display_signalwindow_mmode( app.USMmodeAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_mmode_windowlowerbound', ...
                                                'plot_mmode_windowupperbound');
                end

                drawnow limitrate;

                % update gui table
                gui_updateRowTable( app.USWindowUITable, ...
                                    probeNumber_toShow, ...
                                    [ app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                      app.ust_config.WindowUpperBound(probeNumber_toShow) ] );
            end
            
        end

        % Value changing function: WindowUBSlider
        function WindowUBSliderValueChanging(app, event)
            UB_value = event.Value;

            % to prevent stupidity of the user which maybe will specify 
            % Upper Bound (LB) to be lower than Lower Bound (UB), we need 
            % to move the LB so that the UB will always be higher
            LB_value = app.WindowLBSlider.Value;
            if(UB_value <= LB_value + 0.5 )
                LB_value = UB_value - 0.5;
                
                % if UB reaches slider limit, we clamp it to the limit
                if (LB_value < app.WindowUBSlider.Limits(1))
                    LB_value = app.WindowUBSlider.Limits(1);
                    UB_value = app.WindowUBSlider.Limits(1) + 0.5;
                end

                app.WindowLBSlider.Value = LB_value;
            end

            % (!) ERROR HANDLING: only execute the display updating code if
            % % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData))

                % get the current active probe number
                probeNumber_toShow  = app.ProbeNumberDropDown.Value;
                % update the window lowerbound within ust_config for this
                % spesific probe number
                app.ust_config.WindowLowerBound(probeNumber_toShow) = LB_value;
                app.ust_config.WindowUpperBound(probeNumber_toShow) = UB_value;
                % update also the edit field so user can see the number
                app.WindowLBEditField.Value = num2str(LB_value);
                app.WindowUBEditField.Value = num2str(UB_value);
    
                % draw window on amode
                display_signalwindow_amode( app.USAmodeAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_amode_windowlowerbound', ...
                                            'plot_amode_windowupperbound');

                % (!) ERROR HANDLING: only execute the mmode update if the
                % user already process the signal
                if(~isempty(app.USData_processedsignal))
                    % draw window on mmode
                    display_signalwindow_mmode( app.USMmodeAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_mmode_windowlowerbound', ...
                                                'plot_mmode_windowupperbound');
                end

                drawnow limitrate;

                % update gui table
                gui_updateRowTable( app.USWindowUITable, ...
                                    probeNumber_toShow, ...
                                    [ app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                      app.ust_config.WindowUpperBound(probeNumber_toShow) ] );
            end
        end

        % Value changed function: ProbeNumberDropDown
        function ProbeNumberDropDownValueChanged(app, event)
            probeNumber_toShow = app.ProbeNumberDropDown.Value;

            % (!) ERROR HANDLING: only execute the display updating code if
            % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData))

                % if user change the probe number, let's update the window 
                % sliders and edit fields for LB and UB
                app.WindowLBSlider.Value    = app.ust_config.WindowLowerBound(probeNumber_toShow);
                app.WindowLBEditField.Value = num2str(app.ust_config.WindowLowerBound(probeNumber_toShow));
                app.WindowUBSlider.Value    = app.ust_config.WindowUpperBound(probeNumber_toShow);
                app.WindowUBEditField.Value = num2str(app.ust_config.WindowUpperBound(probeNumber_toShow));
    
                % gather necessary data we want to show
                probeNumber_toShow = app.ProbeNumberDropDown.Value;
                timestamp_toshow   = round(app.USTimestampSlider.Value);

                % (!) ERROR HANDLING: update A- and M-mode with depth
                % (and timestamp) only if the user already detect the peak
                if (~isempty(app.USData_allpeaks))
                    updateUSDisplays(app, app.DISPLAY_US_AM_All, probeNumber_toShow, timestamp_toshow);

                % (!) ERROR HANDLING: update A- and M-mode with only window
                % (not timestamp, since we are not doing anything with it)
                elseif (~isempty(app.USData_processedsignal))
                    updateUSDisplays(app, app.DISPLAY_US_AM_Window, probeNumber_toShow, timestamp_toshow);

                % (!) ERROR HANDLING: update only A-mode if user didn't
                % process the signal
                else
                    updateUSDisplays(app, app.DISPLAY_US_A_Raw, probeNumber_toShow, timestamp_toshow);
                end

                drawnow;
            end

            %  (!) ERROR HANDLING: this part is only for disabling prev-next
            % probe button, so that it prevents options for user to choose 
            % probe number ouside the range by keep pressing button.
            if (app.ProbeNumberDropDown.Value == app.ProbeNumberDropDown.ItemsData(end))
                app.ProbeNextButton.Enable = false;
                app.ProbePrevButton.Enable = true;
            elseif (app.ProbeNumberDropDown.Value == app.ProbeNumberDropDown.ItemsData(1))
                app.ProbeNextButton.Enable = true;
                app.ProbePrevButton.Enable = false;
            else
                app.ProbeNextButton.Enable = true;
                app.ProbePrevButton.Enable = true;
            end
        end

        % Button pushed function: ProbeNextButton
        function ProbeNextButtonPushed(app, event)
            % add the current active transducer
            app.ProbeNumberDropDown.Value = app.ProbeNumberDropDown.Value + 1;

            % trigger dropdown value change event
            ProbeNumberDropDownValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.ProbeNumberDropDown.Value == app.ProbeNumberDropDown.ItemsData(end))
                app.ProbeNextButton.Enable = false;
            else
                app.ProbePrevButton.Enable = true;
            end
        end

        % Button pushed function: ProbePrevButton
        function ProbePrevButtonPushed(app, event)
            % add the current active transducer
            app.ProbeNumberDropDown.Value = app.ProbeNumberDropDown.Value - 1;

            % trigger dropdown value change event
            ProbeNumberDropDownValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.ProbeNumberDropDown.Value == app.ProbeNumberDropDown.ItemsData(1))
                app.ProbePrevButton.Enable = false;
            else
                app.ProbeNextButton.Enable = true;
            end
        end

        % Value changing function: USTimestampSlider
        function USTimestampSliderValueChanging(app, event)
            timestamp_toshow   = round(event.Value);
            probeNumber_toShow = app.ProbeNumberDropDown.Value;

            % (!) ERROR HANDLING: update A- and M-mode with depth
            % (and timestamp) only if the user already detect the peak
            if (~isempty(app.USData_allpeaks))
                updateUSDisplays(app, app.DISPLAY_US_AM_Depth, probeNumber_toShow, timestamp_toshow);

            % (!) ERROR HANDLING: update A- and M-mode with timestamp only 
            % % if user already process the signal
            elseif (~isempty(app.USData_processedsignal))
                updateUSDisplays(app, app.DISPLAY_US_AM_Time, probeNumber_toShow, timestamp_toshow);

            % (!) ERROR HANDLING: only update the A-mode according to 
            % timestamp change only if the user only loaded the raw data
            elseif (~isempty(app.USData))
                updateUSDisplays(app, app.DISPLAY_US_A_Raw, probeNumber_toShow, timestamp_toshow);

            end

            % need to do this, or else the display is not updating
            drawnow limitrate;

            % change the ultrasound timestamp value
            app.USTimestampEditField.Value = timestamp_toshow;
            
        end

        % Button pushed function: USPlayButton
        function USPlayButtonPushed(app, event)
            % (!) ERROR HANDLING: only update the A-mode according to 
            % timestamp change only if the user only loaded the raw data
            if (~isempty(app.USData))
                
                % tell the app that there is animation playing
                app.is_animationUS_playing = true;

                % disable other button that might messed up the play button
                app.USPlayButton.Enable = 'off';
                app.ExportProbeConfigButton.Enable = 'off';
                app.DetectDepthButton.Enable = 'off';
                app.LoadUSDataButton.Enable = 'off';
                app.ProcessUSButton.Enable = 'off';
                % turn on pause button so that the user can stop animation
                app.USPauseButton.Enable = 'on';
    
                % get the current timestamp and probe number
                timestamp_toshow   = round(app.USTimestampSlider.Value);

                % if the timestamp slider already at the end, automatically
                % return it back to the beginning
                if (timestamp_toshow==app.USTimestampSlider.Limits(2))
                    timestamp_toshow = 1;
                end

                % loop from the specified timestamp until the end of frame
                for timestamp=timestamp_toshow:app.data_spec.n_frames

                    % i put this here instead of outside the loop, when the
                    % user change the probe while in the loop, the probe
                    % display will also change
                    probeNumber_toShow = app.ProbeNumberDropDown.Value;

                    % (!) ERROR HANDLING: update A- and M-mode with depth
                    % (and timestamp) only if the user already detect the peak
                    if (~isempty(app.USData_allpeaks))
                        updateUSDisplays(app, app.DISPLAY_US_AM_Depth, probeNumber_toShow, timestamp);

                    % (!) ERROR HANDLING: update A- and M-mode with
                    % timestamp only if user already process the signal
                    elseif (~isempty(app.USData_processedsignal))
                        updateUSDisplays(app, app.DISPLAY_US_AM_Time, probeNumber_toShow, timestamp);

                    % (!) ERROR HANDLING: if the user still not process
                    % anything, just show A-mode
                    else
                        updateUSDisplays(app, app.DISPLAY_US_A_Raw, probeNumber_toShow, timestamp);
                    end

                    % draw the graph now
                    drawnow limitrate;

                    % set the timestamp slider and edit field according to the loop
                    app.USTimestampSlider.Value    = timestamp;
                    app.USTimestampEditField.Value = timestamp;

	                % if user press pause, stop at the last timestamp
                    if (app.USPauseButton.UserData)
                        % set the user data inside pause button to zero and
                        % disable the pause button
                        app.USPauseButton.UserData = 0; 
                        app.USPauseButton.Enable = 'off';
		                break;
                    end

                % end timeframe loop
                end

                % set every button to on again
                app.USPlayButton.Enable = 'on';
                app.ExportProbeConfigButton.Enable = 'on';
                app.DetectDepthButton.Enable = 'on';
                app.LoadUSDataButton.Enable = 'on';
                app.ProcessUSButton.Enable = 'on';
                % don't forget to set the pause button to be off
                app.USPauseButton.Enable = 'off';

                % tell the app that there is no animation playing
                app.is_animationUS_playing = false;

            % end if app.USData
            end
        end

        % Button pushed function: USPauseButton
        function USPauseButtonPushed(app, event)
            app.USPauseButton.UserData = 1;
        end

        % Button pushed function: ExportProbeConfigButton
        function ExportProbeConfigButtonPushed(app, event)
            % get the field names
            ust_struct_fieldnames = fieldnames(app.ust_struct);
            % loop through all the fields
            for i=1:app.data_spec.n_ust
                
                % .ini file from ultrasound machine stores 6 point floating point
                % and (somehow) saved it in string format with comma instead of point,
                % for consistency, we also save the .ini file like that
                lowerbound_str = strrep(sprintf('%.6f', app.ust_config.WindowLowerBound(i)), '.', ',');
                upperbound_str = strrep(sprintf('%.6f', app.ust_config.WindowUpperBound(i)), '.', ',');
                
                % store it to our struct
                app.ust_struct.(ust_struct_fieldnames{i}).LowerBound = lowerbound_str;
                app.ust_struct.(ust_struct_fieldnames{i}).UpperBound = upperbound_str;
            end
            
            % this code requires struct2ini function which can be found here:
            % https://nl.mathworks.com/matlabcentral/fileexchange/22079-struct2ini
            [fname, path] = uiputfile('*.ini', pwd);
            if(path~=0)
                struct2ini( strcat(path, fname), app.ust_struct );
            end
        end

        % Button pushed function: DetectDepthButton
        function DetectDepthButtonPushed(app, event)
            % (!) ERROR HANDLING: check if user already process the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData_processedsignal))
                uialert(app.UIFigure, 'Ultrasound Data is processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                return;
            end
            % (!) ERROR HANDLING: check if user somehow have problem with
            % process data, usually because the user change the settings
            if(app.ProcessUSStatusLamp.UserData == app.STATUS_WARNING || app.ProcessUSStatusLamp.UserData == app.STATUS_ERROR)
                messagestr = {'Please reprocess the ultrasound data first. This can be occured because either: ', ...
                              '1) You changed the settings', ...
                              '2) You changed the path to your data', ...
                              '3) You are breaking physical reality and producing a spacetime rift within our universe'};
                uialert(app.UIFigure, messagestr, 'Warning', 'Icon', 'warning');
                return;
            end

            % define window range
            app.ust_config.WindowRange = [app.ust_config.WindowLowerBound app.ust_config.WindowUpperBound];
            % convert windows mm to windows indices
            app.ust_config.WindowRange_i = floor(app.ust_config.WindowRange / app.us_spec.index2distance_constant + 1);
            
            % signal pre-processing
            path_barkercode = strcat('data', filesep, 'kenans_barkercode.txt');
            [app.USData_allpeaks, ~] = peaks_USsignal_windowed( app.USData, ...
                                                         app.data_spec, ...
                                                         app.us_spec, ...
                                                         app.ust_config.WindowRange, ...
                                                         app.ust_config.WindowRange_i, ...
                                                         path_barkercode);
            
            % save the peaks
            USData_allpeaks = app.USData_allpeaks;
            uisave('USData_allpeaks', 'USData_allpeaks');
            
            % plot the peaks in the m-mode axes
            probeNumber_toShow = app.ProbeNumberDropDown.Value;
            timestamp_toshow   = round(app.USTimestampSlider.Value);
            display_peak_mmode(app.USMmodeAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
            display_peak_amode(app.USAmodeAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');
        end

        % Button pushed function: TRCLocalPathBrowseButton
        function TRCLocalPathBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to TRC file: Qualisys data with computer timestamp');

            % get the directory
            [file_mypc, path_mypc] = uigetfile('*.trc');
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.TRCLocalEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('File to TRC Local file specified as: "%s"', app.TRCLocalEditField.Value));
            end
        end

        % Button pushed function: TRCQualisysPathBrowseButton
        function TRCQualisysPathBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to TRC file: Qualisys data with qualisys timestamp');

            % get the directory
            [file_mypc, path_mypc] = uigetfile('*.trc');
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.TRCQualisysEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('File to TRC Local file specified as: "%s"', app.TRCQualisysEditField.Value));
            end
        end

        % Button pushed function: MatQualisysBrowseButton
        function MatQualisysBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to MAT file: Qualisys data generated from QTM');

            % get the directory
            [file_mypc, path_mypc] = uigetfile('*.mat');
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.MATQualisysEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('File to MAT file specified as: "%s"', app.MATQualisysEditField.Value));
            end
        end

        % Button pushed function: HolderCalibBrowseButton
        function HolderCalibBrowseButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to CSV file: Ultrasound holder calibration file');

            % get the directory
            [file_mypc, path_mypc] = uigetfile('*.csv');
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.HolderCalibEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('Ultrasound holder calibration file specified as: "%s"', app.HolderCalibEditField.Value));
            end
        end

        % Button pushed function: LoadMocapDataButton
        function LoadMocapDataButtonPushed(app, event)
            % ERROR HANDLING: if user still not specified the path, give error message
            if( isempty(app.TRCLocalEditField.Value) || ...
                isempty(app.TRCQualisysEditField.Value) || ...
                isempty(app.MATQualisysEditField.Value) || ...
                isempty(app.HolderCalibEditField.Value) )

                uialert(app.UIFigure, 'Some of the path to required files are still empty', 'Empty Fields', 'Icon', 'error');
                changeLampStatus(app, app.LoadMocapStatusLamp, app.STATUS_ERROR);
                return
            end

            changeLampStatus(app, app.LoadMocapStatusLamp, app.STATUS_WARNING);

            % read file for computer timestamp
            addLog(app, app.MESSAGE_RUNNING, 'Reading TRC with local time data...');
            [~, app.markerdata_mypc]         = readTRC_qualisysData(app.TRCLocalEditField.Value);
            % read file for qualisys timestamp
            addLog(app, app.MESSAGE_RUNNING, 'Reading TRC with qualisys time data...');
            [~, app.markerdata_qualisys] = readTRC_qualisysData(app.TRCQualisysEditField.Value);
            % read file for ultrasound holder calibration
            addLog(app, app.MESSAGE_RUNNING, 'Reading Ultrasound holder calibration file...');
            app.calibration_markerstick = readmatrix(app.HolderCalibEditField.Value);

            % load the Qualisys MAT file. it can be obtained after post 
            % processing of the Qualisys data from QTM software
            addLog(app, app.MESSAGE_RUNNING, 'Reading Qualisys MAT file...');
            load(app.MATQualisysEditField.Value);
            % MAT file that is exported from QTM file doesn't have list of timestamp,
            % so we need to generate it by ourself using framerate information.
            % (!) WARNING:
            % (!) The variable name within .MAT file from QTM depends on the filename.
            % (!) This is so annoying since you need to call a different variable name 
            % (!) for different file. So, i make this "very unrecommended way" to
            % (!) rename the variable to a name we can all agree with. I will name the
            % (!) variable as matqtm_postprocess
            [~, filename, ~] = fileparts(app.MATQualisysEditField.Value);
            eval(['app.matqtm_postprocess = ', filename, ';']);

            % give message to user
            addLog(app, app.MESSAGE_DEFAULT, 'Finished reading Mocap Data.');
            changeLampStatus(app, app.LoadMocapStatusLamp, app.STATUS_SUCCESS);
        end

        % Button pushed function: SynchronizeUSMocapButton
        function SynchronizeUSMocapButtonPushed(app, event)
            % SUMMARY OF FUNCTION:
            % This synchronize US Data (TIFF files), and Mocap Data (TRC
            % Files, and MAT file).
            
            % ERROR HANDLING: check if user already load the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData))
                uialert(app.UIFigure, 'Ultrasound Data is not loaded yet. Please load from the Ultrasound Tab', 'File not found', 'Icon', 'error');
                changeLampStatus(app, app.SynchronizeUSMocapStatusLamp, app.STATUS_ERROR);
                return
            end

            if( isempty(app.markerdata_mypc) || ...
                isempty(app.markerdata_qualisys) || ...
                isempty(app.calibration_markerstick) || ...
                isempty(app.matqtm_postprocess))

                uialert(app.UIFigure, 'Some of the required mocap files are not correctly loaded or are not loaded yet. Please check your files or your paths then load them up again', 'File not found', 'Icon', 'error');
                changeLampStatus(app, app.SynchronizeUSMocapStatusLamp, app.STATUS_ERROR);
                return
            end
            

            % =====================================================================
            % 1) MATCHING BETWEEN US TIFF FILES AND QUALISYS TRC FILES
            %
            % Our first goal is to synchronize US measurement (file #1) and
            % post-processed Qualisys data (file #4). Because there is local time
            % timestamp difference, we will require timestamp information in raw 
            % Qualisys data (file #2 and #3). So, first, let's synchronize the 
            % timestamp between US measurement and the Raw Qualisys Data
            % =====================================================================

            % copy the original data, so i will not overwrite it (maybe the
            % user still want to play around with Ultrasound Tab)
            app.USData_aftersync            = app.USData;
            app.timestamps_USData_aftersync = app.timestamps_USData;

            % match timestamp between US tiff data and Qualisys trc data
            indexMatch_USQualisys = matchTimestamp(app.timestamps_USData_aftersync, app.markerdata_mypc.Time, 0.01);
            indexMatch_USQualisys = unique(indexMatch_USQualisys); % just in case

            % delete the US Data which doesn't have match. This might be the case if US
            % TIFF data has bigger amount than the Qualisys TRC files.
            lastUSindex_hasMatch = length(indexMatch_USQualisys);
            if ( length(app.timestamps_USData_aftersync) > lastUSindex_hasMatch )
                app.timestamps_USData_aftersync(lastUSindex_hasMatch+1:end) = [];
                app.USData_aftersync(:,:,lastUSindex_hasMatch+1:end)        = []; 
            end

            % select qualisys data which do have match
            app.markerdata_mypc     = app.markerdata_mypc(indexMatch_USQualisys,:);
            app.markerdata_qualisys = app.markerdata_qualisys(indexMatch_USQualisys,:);
            
            % calculate average mismatch timestamp
            % for i=1:length(indexMatch_USQualisys)
            %     disp(sprintf('US timestamp: %.5f, \t Qualisys timestamp: %.5f', timestamps_USData(i), markerdata_mypc.Time(i) ));
            % end
            str = sprintf('mean absolute time difference: %d', mean(abs(app.timestamps_USData_aftersync - app.markerdata_mypc.Time)) );
            addLog(app, app.MESSAGE_DEFAULT, str);


            % ========================================================================
            % 2) MATCHING BETWEEN US - QUALISYS TRC FILE AND QUALISYS MAT FILE FROM QTM
            %
            % From the previous section, we obtain markerdata_mypc and
            % markerdata_qualisys which synchronized with USData by their index. Now
            % it is the time to synchronize those data with Qualysis MAT file from
            % QTM software.
            % ========================================================================

            % generate the timestamp
            timestamps_qualisysQTM = generateQualisysTimestamp(app.matqtm_postprocess);
            % match timestamp between Qualisys TRC file and Qualisis .mat (qtm) file
            indexMatch_TRCandQTM = matchTimestamp(app.markerdata_qualisys.Time, timestamps_qualisysQTM, 0.01);

            % delete qualisys TRC file and US TIFF data which doesn't have any match 
            % with qualisys qtm (qualisys TRC file and US TIFF data are matched, so, if
            % one is modified, the other must be modified too). This might be the case
            % if TRC file have bigger amount of record than QTM file
            lastTRCindex_hasMatch = length(indexMatch_TRCandQTM);
            if( size(app.markerdata_qualisys, 1) > lastTRCindex_hasMatch )
                % qualisys TRC data
                app.markerdata_qualisys(lastTRCindex_hasMatch+1:end, :)      = [];
                app.markerdata_mypc(lastTRCindex_hasMatch+1:end, :)          = [];
                % US TIFF data
                app.timestamps_USData_aftersync(lastTRCindex_hasMatch+1:end) = [];
                app.USData_aftersync(:,:,lastTRCindex_hasMatch+1:end)        = [];
            end
            
            % select Qualisys .mat file which do have match (we only focus for R and t
            % field in the structure)
            holder_t_global = app.matqtm_postprocess.RigidBodies.Positions(:,:,indexMatch_TRCandQTM);
            holder_R_global = app.matqtm_postprocess.RigidBodies.Rotations(:,:,indexMatch_TRCandQTM);

            % update our dataframe after synchronization
            app.data_spec.n_frames_aftersync = size(app.USData_aftersync, 3);


            % ============================================================================
            % 3) REFORMATTING THE TRANSFORMATIONS
            % 
            % From the last section, we obtained the depth data from each of our
            % ultrasound probe. Now, if we properly transform our depth data with
            % series of rigid body transformation, we can recover the 3D trajectory of
            % ultrasound measurement.
            %
            % There are several transformation matrix that is required, there are
            % 1. T to Marker Stick in global coordinate frame
            % 2. T to UST tip in holder coordinate frame
            % 3. T to Bone Surface in UST tip coordinate frame
            %
            % WARNING:
            % (!) Instead of T to holder, we have now T to Marker Stick. This is
            % (!) because individual trasnducers is now has markers on it.
            % WARNING:
            % (!) With the current holder that is used in this experiment, we can
            % (!) actually bypass transformation #2, and if we assume the the
            % (!) transducer positioning with the marker stick is perfect, we can
            % (!) further simplify transformation #3 as only translation. In this script,
            % (!) i will assume the positioning is perfect (as i was not conducting a
            % (!) calibration yet, so the transformation is just #1 + translation
            % ==============================================================================


            % First, let's reformat T to holder in global coordinate frame
            % allocate memory for variable
            app.T_global_markerstick = repmat(eye(4),  1, 1, app.data_spec.n_ust, app.data_spec.n_frames_aftersync);

            % (!) WARNING:
            % (!) In the last experiment, i only use 2 trasnducers, it means that i
            % (!) need to adjust that i will only grab 2 transformation from 
            % (!) holder_t_global and holder_R_global. YOU SHOULD CHANGED if you use
            % (!) different number of transducers
            start_ust = str2double(app.StartUSTDropDown.Value);

            for current_frame = 1 : app.data_spec.n_frames_aftersync
                 
                % in previous implementation, we are grouping several transducers into one
                % holder, so several trasnducers shares same transformation #2. but now,
                % because each transducer has individual T, it means that the number of 
                % transducer is the same as the number of T.
                for current_markerstick = start_ust : app.data_spec.n_ust
                    
                    % (!) WARNING:
                    % (!) This line of code below is here because the previous warning,
                    % (!) i only use 2 transducers. So i need to adjust the indexing so
                    % (!) i can store holder_t_global and holder_R_global properly.
                    % (!) YOU SHOULD CHANGE if you use different number of transducers
                    adjusted_idx = (current_markerstick - start_ust) + 1;
                    
                    % obtain transfomation data
                    t = holder_t_global(adjusted_idx, :, current_frame)';
                    R = reshape(holder_R_global(adjusted_idx, :, current_frame), 3,3);
                    % rearrange holder_t_global and holder_R_global to homogeneous
                    % transformation matrix [R, t; 0 1]
                    app.T_global_markerstick(:, :, current_markerstick, current_frame) = [ R, t; zeros(1,3), 1];
                end
            end

            % Second, let's reformat T to UST tip in holder coordinate frame. This
            % transformation is stored in calibration file (.CSV).            
            % allocate memory for variable
            app.T_markerstick_usttip = repmat(eye(4), 1, 1, app.data_spec.n_ust);
            
            for current_ust=1:app.data_spec.n_ust
                % each line of calibration_markerstick is calibration for 1 transducer
                app.T_markerstick_usttip(:,:,current_ust) = reshape(app.calibration_markerstick(current_ust,:), [4,4])';
            end

            % change status lamp that syncronization is successfull
            changeLampStatus(app, app.SynchronizeUSMocapStatusLamp, app.STATUS_SUCCESS);
            % disable the button, so that the user not doing synchronization twice
            app.SynchronizeUSMocapButton.Enable = false;
            
        end

        % Button pushed function: LoadDisplayOptionButton
        function LoadDisplayOptionButtonPushed(app, event)
            % allocate memory for variable
            app.pc_amode  = zeros(app.data_spec.n_ust, 3, app.data_spec.n_frames_aftersync);
            app.pc_usttip = zeros(app.data_spec.n_ust, 3, app.data_spec.n_frames_aftersync);

            % get trasnducers value being used
            start_ust = str2double(app.StartUSTDropDown.Value);
            end_ust   = str2double(app.EndUSTDropDown.Value);

            % compute all the transformations 
            for current_frame = 1:app.data_spec.n_frames_aftersync
                for current_ust = start_ust : end_ust
                    
                    % only loads if the user specify A-mode Switch On
                    if(strcmp(app.AmodeSwitch.Value, 'On'))
                        % two steps of transformation for A-mode
                        amode = app.T_global_markerstick(:,:, current_ust, current_frame) * ...
                                app.T_markerstick_usttip(:,:, current_ust) * ...
                                [0, 0, allpeaks.locations(current_ust, current_frame), 1]';
            
                        % (//) uncomment this to follow qualisys base vector
                        amode = [app.basevector_Rcorrection, zeros(3,1); zeros(1,3), 1] * amode;

                        % from homogeneous back to cartesian
                        app.pc_amode(current_ust, :, current_frame) = amode(1:3)';
                    end
                    
                    % only loads if the user specify A-mode Switch On
                    if(strcmp(app.USTipsSwitch.Value, 'On'))                        
                        % for displaying UST Tips, we just introduced zero
                        % homogeneous vector
                        usttip = app.T_global_markerstick(:,:, current_ust, current_frame) * ...
                                 app.T_markerstick_usttip(:,:, current_ust) * ...
                                 [0, 0, 0, 1]';
                        usttip = [app.basevector_Rcorrection, zeros(3,1); zeros(1,3), 1] * usttip;
                        
                        % from homogeneous back to cartesian
                        app.pc_usttip(current_ust, :, current_frame) = usttip(1:3)';
                    end
                    
                end                
            end

            % display (only with user configuration on GUI)
            start_frame = app.TrajectoryNumberEditField.Value + 1;


            % only loads if the user specify A-mode Switch On
            if(strcmp(app.AmodeSwitch.Value, 'On'))
                % plot the amode
                delete(findobj('Tag', 'plot_amode'));
                plot3( app.UIAxesMocap, ...
                       app.pc_amode(start_ust : end_ust, 1, start_frame), ...
                       app.pc_amode(start_ust : end_ust, 2, start_frame), ...
                       app.pc_amode(start_ust : end_ust, 3, start_frame), ...
                      '.r', 'MarkerFaceColor', 'red', 'Tag', 'plot_amode');
	            % plot the US-tip
            end

            % only loads if the user specify A-mode Switch On
            if(strcmp(app.USTipsSwitch.Value, 'On'))
                % plot UST Tips
                delete(findobj('Tag', 'plot_usttip'));
                plot3( app.UIAxesMocap, ...
                       app.pc_usttip(start_ust : end_ust, 1, start_frame), ...
                       app.pc_usttip(start_ust : end_ust, 2, start_frame), ...
                       app.pc_usttip(start_ust : end_ust, 3, start_frame), ...
                      'ob', 'MarkerFaceColor', 'blue', 'Tag', 'plot_usttip');
                % plot the text for indicator
                delete(findobj('Tag', 'plot_ustext'));
                for current_ust = start_ust : end_ust
                    text( app.UIAxesMocap, ...
                          app.pc_usttip(current_ust, 1, start_frame)+app.offset_ustext, ...
                          app.pc_usttip(current_ust, 2, start_frame)+app.offset_ustext, ...
                          app.pc_usttip(current_ust, 3, start_frame)+app.offset_ustext, ...
                          sprintf('US%2d', current_ust), ...
                          'Tag', 'plot_ustext');
                end
            end

            % write the title with frame number
            title(app.UIAxesMocap, sprintf('Reconstructed A-mode, Frame %d', start_frame));

        end

        % Value changed function: AmodeSwitch
        function AmodeSwitchValueChanged(app, event)
            if(strcmp(app.AmodeSwitch.Value, 'On'))
                app.TrajectorySwitch.Enable = true;
                app.TrajectoryLabel.Enable  = true;
                if (strcmp(app.TrajectorySwitch.Value, 'On'))
                    app.TrajectoryNumberEditField.Enable = true;
                else
                    app.TrajectoryNumberEditField.Enable = false;
                end
            else
                app.TrajectorySwitch.Enable          = false;
                app.TrajectoryLabel.Enable           = false;
                app.TrajectoryNumberEditField.Enable = false;
                app.TrajectorySwitch.Value           = 'Off';
                app.TrajectoryNumberEditField.Value  = 0;
            end
        end

        % Value changed function: TrajectorySwitch
        function TrajectorySwitchValueChanged(app, event)
            value = app.TrajectorySwitch.Value;            

            if(strcmp(value, 'On'))
                app.TrajectoryNumberEditField.Enable = true;
            else
                app.TrajectoryNumberEditField.Enable = false;
            end
        end

        % Button pushed function: USFilesLoadButton
        function USFilesLoadButtonPushed(app, event)
            addLog(app, app.MESSAGE_DEFAULT, 'Get path to TIFF files: Ultrasound raw signal');

            % ============================================================
            % LOAD AND PROCESS AMODE DATA
            % ============================================================

            % get the directory
            directory_toUSData = uigetdir('data\');
            % show the directory to editfield if the user click open
            if(directory_toUSData~=0)
                app.USFilesEditField.Value = directory_toUSData;
                addLog(app, app.MESSAGE_DEFAULT, sprintf('Path to TIFF file specified as: "%s"', directory_toUSData));
            else
                % if user hit cancel
                return;
            end
            
            % read data
            addLog(app, app.MESSAGE_RUNNING, 'Reading ultrasound signal data...');
            [app.USData, app.timestamps_USData, ~] = readTIFF_USsignal(app.USFilesEditField.Value, 30, 2500);

            % preparing data_spec structs which needed by the peak detection algorithm
            app.data_spec.n_ust     = size(app.USData, 1);
            app.data_spec.n_samples = size(app.USData, 2);
            app.data_spec.n_frames  = size(app.USData, 3);
            % preparing us_spec structs which needed by the peak detection algorithm
            app.us_spec.x_axis_values = (1:app.data_spec.n_samples) .* app.us_spec.index2distance_constant; % mm
            app.us_spec.t_vector      = ((1:app.data_spec.n_samples) .* app.us_spec.period) * 1e6; % mu s

            % ============================================================
            % SHOW AMODE DATA
            % ============================================================

            probeNumber_toShow = app.ProbeNumDropDown.Value;

            % reset the axes
            cla(app.USAmodeSignalAxes);
            cla(app.USMmodeSignalAxes);

            % show the data
            timestamp_toshow   = round(app.USTimestampSlider_2.Value);
            updateUSDisplays3(app, [1 0 0 0 0], probeNumber_toShow, timestamp_toshow);

            % reset the processed and depth data
            if(~isempty(app.USData_allpeaks))
                app.USData_allpeaks = [];
            end
            if(~isempty(app.USData_processedsignal))
                app.USData_processedsignal = [];
            end

            % ============================================================
            % CONFIGURE THE INTERFACE
            % ============================================================

            % set the timestamp slider to the same amount of USData frames
            app.USTimestampSlider_2.Limits = [1 app.data_spec.n_frames];
            % set the timeframe maximum label to maximum frame we have
            app.USTimestampMaxLabel_2.Text = sprintf('/ %s', num2str(app.data_spec.n_frames));

        end

        % Button pushed function: USFilesProcessButton
        function USFilesProcessButtonPushed(app, event)
            % SUMMARY OF FUNCTION:
            % As the name suggests, this function will handle the digital 
            % signal processing of the ultrasound raw data. The depth is
            % not detected by this function yet
            
            % (!) ERROR HANDLING: check if user already load the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData))
                uialert(app.UIFigure, 'Ultrasound Data is not loaded yet. Please load from the Ultrasound Tab', 'File not found', 'Icon', 'error');
                return
            end

            % processing US data
            addLog(app, app.MESSAGE_RUNNING, 'Processing ultrasound signal data...');
            
            % signal processing
            path_barkercode = strcat('data', filesep, 'kenans_barkercode.txt');
            [app.USData_processedsignal.envelope, app.USData_processedsignal.correlated] = process_USsignal2(app.USData, app.data_spec, app.us_spec, path_barkercode, 'default');
            
            % gather necessary data we want to show
            probeNumber_toShow = app.ProbeNumDropDown.Value;
            timestamp_toshow   = round(app.USTimestampSlider_2.Value);

            % show processed a-mode and m-mode image (without window)
            updateUSDisplays3(app, [0 1 1 1 0], probeNumber_toShow, timestamp_toshow);            

            % give indicators to user
            addLog(app, app.MESSAGE_DEFAULT, 'Finished processing ultrasound signal data...');
        end

        % Selection changed function: WindowConfigurationButtonGroup
        function WindowConfigurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowConfigurationButtonGroup.SelectedObject;
            
            if (strcmp(selectedButton.Text, 'Existing Window Configuration Files'))
                app.windowoption_selected = 1;

                app.WindowConfEditField.Enable  = 'on';
                app.WindowConfButton.Enable     = 'on';
                app.WindowCalibEditField.Enable = 'off';
                app.WindowCalibButton.Enable    = 'off';

                app.USWindowUBSlider.Enable    = 'on';
                app.USWindowUBEditField.Enable = 'on';
                app.USWindowLBSlider.Enable    = 'on';
                app.USWindowLBEditField.Enable = 'on';
                app.USWindowConfUITable.Enable   = 'on';

            elseif (strcmp(selectedButton.Text, 'Automatic Window Calibration'))
                app.windowoption_selected = 2;
                
                app.WindowConfEditField.Enable  = 'off';
                app.WindowConfButton.Enable     = 'off';
                app.WindowCalibEditField.Enable = 'on';
                app.WindowCalibButton.Enable    = 'on';

                app.USWindowUBSlider.Enable    = 'on';
                app.USWindowUBEditField.Enable = 'on';
                app.USWindowLBSlider.Enable    = 'on';
                app.USWindowLBEditField.Enable = 'on';
                app.USWindowConfUITable.Enable   = 'on';

            else
                app.windowoption_selected = 3;

                app.WindowConfEditField.Enable  = 'off';
                app.WindowConfButton.Enable     = 'off';
                app.WindowCalibEditField.Enable = 'off';
                app.WindowCalibButton.Enable    = 'off';

                app.USWindowUBSlider.Enable    = 'off';
                app.USWindowUBEditField.Enable = 'off';
                app.USWindowLBSlider.Enable    = 'off';
                app.USWindowLBEditField.Enable = 'off';
                app.USWindowConfUITable.Enable   = 'off';
            end

        end

        % Button pushed function: WindowConfButton
        function WindowConfButtonPushed(app, event)
            % (!) ERROR HANDLING: check if user already process the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData_processedsignal))
                uialert(app.UIFigure, 'Ultrasound Data is processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                return;
            end

            % (!) ERROR HANDLING: check if the user already load the 
            % ultrasound window configuration file, if yes, ask for
            % confirmation, otherwise the data will be replaced.
            if(~isempty(app.ust_config))
                selection = uiconfirm(app.UIFigure, ['You already loaded the ultrasound window config file ' ...
                                                     'If you continue the window configuration will be replaced.'], ...
                                                     'Confirmation Warning', ...
                                                     'Options', {'Replace', 'Cancel'}, ...
                                                     'DefaultOption', 2, 'CancelOption', 2, ...
                                                     'Icon', 'warning');

                % if user cancel, return, else continue the procedure
                if(strcmp(selection, 'Cancel'))
                    addLog(app, app.MESSAGE_WARNING, 'User pressed cancel when prompted that the window configuration data will be replaced');
                    return;
                end
            end

            % ============================================================
            % READ THE ULTRASOUND CONFIGURATION DATA
            % ============================================================

            addLog(app, app.MESSAGE_DEFAULT, 'Get path to INI file: Ultrasound configuration file');

            % get the directory
            [file_mypc, path_mypc] = uigetfile({'data\*.ini'});
            % show the directory to editfield if the user click open
            if(path_mypc~=0)
                app.WindowConfEditField.Value = strcat(path_mypc, file_mypc);
                addLog(app, app.MESSAGE_DEFAULT, sprintf('Ultrasound configuration file specified as: "%s"', app.WindowConfEditField.Value));

                % clear the other option to indicate that the user loaded
                % window data through this option
                app.WindowCalibEditField.Value = [];
            else
                % if user press cancel
                return;
            end

            % obtain ultrasound settings, we need window information for peak
            % detection, and we also need several other information for the next phases
            addLog(app, app.MESSAGE_RUNNING, 'Reading ultrasound configuration data...');
            app.ust_struct = ini2struct(app.WindowConfEditField.Value);

            % ============================================================
            % PREPARING ULTRASOUND CONFIGURATION DATA
            % ============================================================

            % initialize a table for containing the probe properties
            app.ust_config = table( 'Size', [1,3], ...
                                'VariableTypes', ["string", "double", "double"], ...
                                'VariableNames', ["Group", "WindowLowerBound", "WindowUpperBound"]);
            % get the field names
            ust_struct_fieldnames = fieldnames(app.ust_struct);
            % loop through all the fields
            for i=1:app.data_spec.n_ust
                % get lower and upper bound data
                lowerbound_str = app.ust_struct.(ust_struct_fieldnames{i}).LowerBound;
                upperbound_str = app.ust_struct.(ust_struct_fieldnames{i}).UpperBound;
                group_str      = app.ust_struct.(ust_struct_fieldnames{i}).Group;
                
                % but because it is string, and the US machine somehow uses comma 
                % separator for floating point, so we need to replace the comma as 
                % point first, then convert it to double.
                app.ust_config.WindowLowerBound(i) = str2double(strrep(lowerbound_str, ',', '.'));
                app.ust_config.WindowUpperBound(i) = str2double(strrep(upperbound_str, ',', '.'));
                % remove double quote in the group string from ini fle
                app.ust_config.Group(i) = strrep(group_str, '"', '');
            end

            % ============================================================
            % CONFIGURE THE INTERFACE
            % ============================================================

            probeNumber_toShow = app.ProbeNumDropDown.Value;
            timestamp_toshow   = round(app.USTimestampSlider_2.Value);

            % set the timestamp slider to the same amount of USData frames
            app.USTimestampSlider_2.Limits = [1 app.data_spec.n_frames];
            % set the timeframe maximum label to maximum frame we have
            app.USTimestampMaxLabel_2.Text = sprintf('/ %s', num2str(app.data_spec.n_frames));

            % set the window sliders and edit field according to data
            app.USWindowLBSlider.Value    = app.ust_config.WindowLowerBound(probeNumber_toShow);
            app.USWindowUBSlider.Value    = app.ust_config.WindowUpperBound(probeNumber_toShow);
            app.USWindowLBEditField.Value = num2str(app.ust_config.WindowLowerBound(probeNumber_toShow));
            app.USWindowUBEditField.Value = num2str(app.ust_config.WindowUpperBound(probeNumber_toShow));
            app.USWindowLBSlider.Limits   = [app.us_spec.x_axis_values(1) app.us_spec.x_axis_values(end)];
            app.USWindowUBSlider.Limits   = [app.us_spec.x_axis_values(1) app.us_spec.x_axis_values(end)];

            % set the uitable according to the data
            app.USWindowConfUITable.Data = [app.ust_config.WindowLowerBound, app.ust_config.WindowUpperBound];

            % show processed a-mode and window, m-mode + window
            updateUSDisplays3(app, [0 2 2 0 0], probeNumber_toShow, timestamp_toshow);

            % change the flag to indicate from which option this window is
            % loaded
            app.windowoption_loaded = 1;

        end

        % Button pushed function: WindowCalibButton
        function WindowCalibButtonPushed(app, event)
            % (!) ERROR HANDLING: check if user already process the US Data, 
            % otherwise we can't proceed the process to synchronize
            if(isempty(app.USData_processedsignal))
                uialert(app.UIFigure, 'Ultrasound Data is processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                return;
            end

            % (!) ERROR HANDLING: check if the user already load the 
            % ultrasound window configuration file, if yes, ask for
            % confirmation, otherwise the data will be replaced.
            if(~isempty(app.ust_config))
                selection = uiconfirm(app.UIFigure, ['You already loaded the ultrasound window config file ' ...
                                                     'If you continue the window configuration will be replaced.'], ...
                                                     'Confirmation Warning', ...
                                                     'Options', {'Replace', 'Cancel'}, ...
                                                     'DefaultOption', 2, 'CancelOption', 2, ...
                                                     'Icon', 'warning');

                % if user cancel, return, else continue the procedure
                if(strcmp(selection, 'Cancel'))
                    addLog(app, app.MESSAGE_WARNING, 'User pressed cancel when prompted that the window configuration data will be replaced');
                    return;
                end
            end

            % start the calibration here


            % change the flag to indicate from which option this window is
            % loaded
            app.windowoption_loaded = 2;

        end

        % Value changed function: ProbeNumDropDown
        function ProbeNumDropDownValueChanged(app, event)
            probeNumber_toShow = app.ProbeNumDropDown.Value;

            % (!) ERROR HANDLING: only execute the display updating code if
            % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData))

                % (!) ERROR HANDLING: only change the window configuration
                % if the user already load the config file
                if(~isempty(app.ust_config))
                    % if user change the probe number, let's update the window 
                    % sliders and edit fields for LB and UB
                    app.USWindowLBSlider.Value    = app.ust_config.WindowLowerBound(probeNumber_toShow);
                    app.USWindowLBEditField.Value = num2str(app.ust_config.WindowLowerBound(probeNumber_toShow));
                    app.USWindowUBSlider.Value    = app.ust_config.WindowUpperBound(probeNumber_toShow);
                    app.USWindowUBEditField.Value = num2str(app.ust_config.WindowUpperBound(probeNumber_toShow));
                end
    
                % gather necessary data we want to show
                probeNumber_toShow = app.ProbeNumDropDown.Value;
                timestamp_toshow   = round(app.USTimestampSlider_2.Value);

                % (!) ERROR HANDLING: update A- and M-mode with depth
                % (and timestamp) only if the user already detect the peak
                if (~isempty(app.USData_allpeaks))
                    % there is no need to check the ust_struct (window)
                    % if the user already have the peaks, meaning that
                    % the user definitely already have the window. Instead,
                    % we check whether the user have usable_timestamp (from
                    % offline processing)
                    if(~isempty(app.usable_timestamp))
                        updateUSDisplays3(app, [0 3 3 1 1], probeNumber_toShow, timestamp_toshow);
                    else
                        updateUSDisplays3(app, [0 3 2 1 1], probeNumber_toShow, timestamp_toshow);
                    end

                % (!) ERROR HANDLING: update A- and M-mode with only window
                % (not timestamp, since we are not doing anything with it)
                elseif (~isempty(app.USData_processedsignal))
                    if(~isempty(app.ust_config))
                        updateUSDisplays3(app, [0 2 2 1 0], probeNumber_toShow, timestamp_toshow);
                    else
                        updateUSDisplays3(app, [0 1 1 1 0], probeNumber_toShow, timestamp_toshow);
                    end

                % (!) ERROR HANDLING: update only A-mode if user didn't
                % process the signal
                else
                    if(~isempty(app.ust_config))
                        updateUSDisplays3(app, [2 0 0 0 0], probeNumber_toShow, timestamp_toshow);
                    else
                        updateUSDisplays3(app, [1 0 0 0 0], probeNumber_toShow, timestamp_toshow);
                    end
                end

                drawnow;
            end

            %  (!) ERROR HANDLING: this part is only for disabling prev-next
            % probe button, so that it prevents options for user to choose 
            % probe number ouside the range by keep pressing button.
            if (app.ProbeNumDropDown.Value == app.ProbeNumDropDown.ItemsData(end))
                app.ProbeNumNextButton.Enable = false;
                app.ProbeNumPrevButton.Enable = true;
            elseif (app.ProbeNumDropDown.Value == app.ProbeNumDropDown.ItemsData(1))
                app.ProbeNumNextButton.Enable = true;
                app.ProbeNumPrevButton.Enable = false;
            else
                app.ProbeNumNextButton.Enable = true;
                app.ProbeNumPrevButton.Enable = true;
            end
        end

        % Button pushed function: ProbeNumNextButton
        function ProbeNumNextButtonPushed(app, event)
            % add the current active transducer
            app.ProbeNumDropDown.Value = app.ProbeNumDropDown.Value + 1;

            % trigger dropdown value change event
            ProbeNumDropDownValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.ProbeNumDropDown.Value == app.ProbeNumDropDown.ItemsData(end))
                app.ProbeNumNextButton.Enable = false;
            else
                app.ProbeNumPrevButton.Enable = true;
            end
        end

        % Button pushed function: ProbeNumPrevButton
        function ProbeNumPrevButtonPushed(app, event)
            % add the current active transducer
            app.ProbeNumDropDown.Value = app.ProbeNumDropDown.Value - 1;

            % trigger dropdown value change event
            ProbeNumDropDownValueChanged(app, event);

            % to even make sure there is no overflow, let's disable the
            % button ones it reached the max
            if (app.ProbeNumDropDown.Value == app.ProbeNumDropDown.ItemsData(1))
                app.ProbeNumPrevButton.Enable = false;
            else
                app.ProbeNumNextButton.Enable = true;
            end
        end

        % Value changing function: USTimestampSlider_2
        function USTimestampSlider_2ValueChanging(app, event)
            timestamp_toshow   = round(event.Value);
            probeNumber_toShow = app.ProbeNumDropDown.Value;

            % (!) ERROR HANDLING: update A- and M-mode with depth
            % (and timestamp) only if the user already detect the peak
            if (~isempty(app.USData_allpeaks))
                % there is no need to check the ust_struct (window)
                % if the user already have the peaks, meaning that
                % the user definitely already have the window. Instead,
                % we check whether the user have usable_timestamp (from
                % offline processing)
                if(~isempty(app.usable_timestamp))
                    updateUSDisplays3(app, [0 3 3 1 1], probeNumber_toShow, timestamp_toshow);
                else
                    updateUSDisplays3(app, [0 3 2 1 1], probeNumber_toShow, timestamp_toshow);
                end

            % (!) ERROR HANDLING: update A- and M-mode with only window
            % (not timestamp, since we are not doing anything with it)
            elseif (~isempty(app.USData_processedsignal))
                if(~isempty(app.ust_config))
                    updateUSDisplays3(app, [0 2 2 1 0], probeNumber_toShow, timestamp_toshow);
                else
                    updateUSDisplays3(app, [0 1 1 1 0], probeNumber_toShow, timestamp_toshow);
                end

            % (!) ERROR HANDLING: update only A-mode if user didn't
            % process the signal
            else
                if(~isempty(app.ust_config))
                    updateUSDisplays3(app, [2 0 0 0 0], probeNumber_toShow, timestamp_toshow);
                else
                    updateUSDisplays3(app, [1 0 0 0 0], probeNumber_toShow, timestamp_toshow);
                end
            end

            % need to do this, or else the display is not updating
            drawnow limitrate;

            % change the ultrasound timestamp value
            app.USTimestampEditField_2.Value = timestamp_toshow;
            
        end

        % Button pushed function: USPlayButton_2
        function USPlayButton_2Pushed(app, event)
            % (!) ERROR HANDLING: only update the A-mode according to 
            % timestamp change only if the user only loaded the raw data
            if (~isempty(app.USData))
                
                % tell the app that there is animation playing
                app.is_animationUS_playing = true;

                % disable other button that might messed up the play button
                app.USPlayButton_2.Enable            = 'off';
                app.ExportProbeConfigButton_2.Enable = 'off';
                app.DetectDepthButton_2.Enable       = 'off';
                app.USFilesEditField.Enable          = 'off';
                app.USFilesLoadButton.Enable         = 'off';
                app.USFilesProcessButton.Enable      = 'off';
                if(app.WindowConfRadioButton.Value)
                    app.WindowConfEditField.Enable   = 'off';
                    app.WindowConfButton.Enable      = 'off';
                elseif(app.WindowCalibRadioButton.Value)
                    app.WindowCalibEditField.Enable  = 'off';
                    app.WindowCalibButton.Enable     = 'off';
                end
                app.WindowConfRadioButton.Enable     = 'off';
                app.WindowCalibRadioButton.Enable    = 'off';
                app.WindowNoneRadioButton.Enable     = 'off';
                % turn on pause button so that the user can stop animation
                app.USPauseButton_2.Enable = 'on';
    
                % get the current timestamp and probe number
                timestamp_toshow   = round(app.USTimestampSlider_2.Value);

                % if the timestamp slider already at the end, automatically
                % return it back to the beginning
                if (timestamp_toshow==app.USTimestampSlider_2.Limits(2))
                    timestamp_toshow = 1;
                end

                % loop from the specified timestamp until the end of frame
                for timestamp=timestamp_toshow:app.data_spec.n_frames

                    % i put this here instead of outside the loop, when the
                    % user change the probe while in the loop, the probe
                    % display will also change
                    probeNumber_toShow = app.ProbeNumDropDown.Value;

                    % (!) ERROR HANDLING: update A- and M-mode with depth
                    % (and timestamp) only if the user already detect the peak
                    if (~isempty(app.USData_allpeaks))
                        % there is no need to check the ust_struct (window)
                        % if the user already have the peaks, meaning that
                        % the user definitely already have the window. Instead,
                        % we check whether the user have usable_timestamp (from
                        % offline processing)
                        if(~isempty(app.usable_timestamp))
                            updateUSDisplays3(app, [0 3 3 1 1], probeNumber_toShow, timestamp);
                        else
                            updateUSDisplays3(app, [0 3 2 1 1], probeNumber_toShow, timestamp);
                        end
        
                    % (!) ERROR HANDLING: update A- and M-mode with only window
                    % (not timestamp, since we are not doing anything with it)
                    elseif (~isempty(app.USData_processedsignal))
                        if(~isempty(app.ust_config))
                            updateUSDisplays3(app, [0 2 2 1 0], probeNumber_toShow, timestamp);
                        else
                            updateUSDisplays3(app, [0 1 1 1 0], probeNumber_toShow, timestamp);
                        end
        
                    % (!) ERROR HANDLING: update only A-mode if user didn't
                    % process the signal
                    else
                        if(~isempty(app.ust_config))
                            updateUSDisplays3(app, [2 0 0 0 0], probeNumber_toShow, timestamp);
                        else
                            updateUSDisplays3(app, [1 0 0 0 0], probeNumber_toShow, timestamp);
                        end
                    end

                    % draw the graph now
                    drawnow limitrate;

                    % set the timestamp slider and edit field according to the loop
                    app.USTimestampSlider_2.Value    = timestamp;
                    app.USTimestampEditField_2.Value = timestamp;

	                % if user press pause, stop at the last timestamp
                    if (app.USPauseButton_2.UserData)
                        % set the user data inside pause button to zero and
                        % disable the pause button
                        app.USPauseButton_2.UserData = 0; 
                        app.USPauseButton_2.Enable = 'off';
		                break;
                    end

                % end timeframe loop
                end

                % set every button to on again
                app.USPlayButton_2.Enable            = 'on';
                app.ExportProbeConfigButton_2.Enable = 'on';
                app.DetectDepthButton_2.Enable       = 'on';
                app.USFilesEditField.Enable          = 'on';
                app.USFilesLoadButton.Enable         = 'on';
                app.USFilesProcessButton.Enable      = 'on';
                if(app.WindowConfRadioButton.Value)
                    app.WindowConfEditField.Enable   = 'on';
                    app.WindowConfButton.Enable      = 'on';
                elseif(app.WindowCalibRadioButton.Value)
                    app.WindowCalibEditField.Enable  = 'on';
                    app.WindowCalibButton.Enable     = 'on';
                end
                app.WindowConfRadioButton.Enable     = 'on';
                app.WindowCalibRadioButton.Enable    = 'on';
                app.WindowNoneRadioButton.Enable     = 'on';
                % don't forget to set the pause button to be off
                app.USPauseButton_2.Enable = 'off';

                % tell the app that there is no animation playing
                app.is_animationUS_playing = false;

            % end if app.USData
            end
        end

        % Button pushed function: USPauseButton_2
        function USPauseButton_2Pushed(app, event)
            app.USPauseButton_2.UserData = 1;
        end

        % Value changing function: USWindowLBSlider
        function USWindowLBSliderValueChanging(app, event)
            LB_value = event.Value;

            % to prevent stupidity of the user which maybe will specify 
            % Lower Bound (LB) to be higher than Upper Bound (UB), we need 
            % to move the UB so that the LB will always be lower
            UB_value = app.USWindowUBSlider.Value;
            if(LB_value >= UB_value - 0.5)
                UB_value = LB_value + 0.5;
                
                % if UB reaches slider limit, we clamp it to the limit
                if (UB_value > app.USWindowUBSlider.Limits(2))
                    LB_value = app.USWindowUBSlider.Limits(2) - 0.5;
                    UB_value = app.USWindowUBSlider.Limits(2);
                end

                app.USWindowUBSlider.Value = UB_value;
            end


            % update also the edit field so user can see the number
            app.USWindowLBEditField.Value = num2str(LB_value);
            app.USWindowUBEditField.Value = num2str(UB_value);

            % (!) ERROR HANDLING: only execute the display updating code if
            % % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData) && ~isempty(app.ust_config))

                % get the current active probe number
                probeNumber_toShow  = app.ProbeNumDropDown.Value;
                % update the window lowerbound within ust_config for this
                % spesific probe number
                app.ust_config.WindowLowerBound(probeNumber_toShow) = LB_value;
                app.ust_config.WindowUpperBound(probeNumber_toShow) = UB_value;
    
                % draw window on amode
                display_signalwindow_amode( app.USAmodeSignalAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_amode_windowlowerbound', ...
                                            'plot_amode_windowupperbound');

                % (!) ERROR HANDLING: only execute the mmode update if the
                % user already process the signal
                if(~isempty(app.USData_processedsignal))
                    % draw window on mmode
                    display_signalwindow_mmode( app.USMmodeSignalAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_mmode_windowlowerbound', ...
                                                'plot_mmode_windowupperbound');
                end

                drawnow limitrate;

                % update gui table
                gui_updateRowTable( app.USWindowConfUITable, ...
                                    probeNumber_toShow, ...
                                    [ app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                      app.ust_config.WindowUpperBound(probeNumber_toShow) ] );
            end
        end

        % Value changing function: USWindowUBSlider
        function USWindowUBSliderValueChanging(app, event)
            UB_value = event.Value;

            % to prevent stupidity of the user which maybe will specify 
            % Upper Bound (LB) to be lower than Lower Bound (UB), we need 
            % to move the LB so that the UB will always be higher
            LB_value = app.USWindowLBSlider.Value;
            if(UB_value <= LB_value + 0.5 )
                LB_value = UB_value - 0.5;
                
                % if UB reaches slider limit, we clamp it to the limit
                if (LB_value < app.USWindowUBSlider.Limits(1))
                    LB_value = app.USWindowUBSlider.Limits(1);
                    UB_value = app.USWindowUBSlider.Limits(1) + 0.5;
                end

                app.USWindowLBSlider.Value = LB_value;
            end

            % update also the edit field so user can see the number
            app.USWindowLBEditField.Value = num2str(LB_value);
            app.USWindowUBEditField.Value = num2str(UB_value);

            % (!) ERROR HANDLING: only execute the display updating code if
            % % user alread load the ultrasound signal data in the first place
            if(~isempty(app.USData) && ~isempty(app.ust_config))

                % get the current active probe number
                probeNumber_toShow  = app.ProbeNumDropDown.Value;
                % update the window lowerbound within ust_config for this
                % spesific probe number
                app.ust_config.WindowLowerBound(probeNumber_toShow) = LB_value;
                app.ust_config.WindowUpperBound(probeNumber_toShow) = UB_value;
    
                % draw window on amode
                display_signalwindow_amode( app.USAmodeSignalAxes, ...
                                            app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                            app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                            'plot_amode_windowlowerbound', ...
                                            'plot_amode_windowupperbound');

                % (!) ERROR HANDLING: only execute the mmode update if the
                % user already process the signal
                if(~isempty(app.USData_processedsignal))
                    % draw window on mmode
                    display_signalwindow_mmode( app.USMmodeSignalAxes, ...
                                                app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                                app.ust_config.WindowUpperBound(probeNumber_toShow), ...
                                                'plot_mmode_windowlowerbound', ...
                                                'plot_mmode_windowupperbound');
                end

                drawnow limitrate;

                % update gui table
                gui_updateRowTable( app.USWindowConfUITable, ...
                                    probeNumber_toShow, ...
                                    [ app.ust_config.WindowLowerBound(probeNumber_toShow), ...
                                      app.ust_config.WindowUpperBound(probeNumber_toShow) ] );
            end
        end

        % Button pushed function: DetectDepthButton_2
        function DetectDepthButton_2Pushed(app, event)
           
            % ============================================================
            % EXISTING WINDOW CONFIGURATION FILE
            % ============================================================
            
            if (app.WindowConfRadioButton.Value)

                % (!) ERROR HANDLING: check if user already process the US 
                % Data, otherwise we can't proceed the process to detect peak
                if(isempty(app.USData_processedsignal))
                    uialert(app.UIFigure, 'Ultrasound Data is not processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                    return;
                end

                % (!) ERROR HANDLING: check if the user already load the 
                % ultrasound window configuration file, if not, we can't
                % proceed the peak detection.
                if(isempty(app.ust_struct))
                    uialert(app.UIFigure, [ 'You choose "Existing Window Configuration File" while you are not loading the data yet.' ...
                                            'Please load the ultrasound window config file (.ini) first then continue. '], ...
                                            'Data not found', 'Icon', 'error');

                    addLog(app, app.MESSAGE_WARNING, 'Peak detection canceled because of window configuration file was not loaded yet');
                    return;
                end

                % (!) ERROR HANDLING: check if the user already load the 
                % ultrasound window configuration file, but choose
                % different option
                if(app.windowoption_selected ~= app.windowoption_loaded)
                    selection = uiconfirm(app.UIFigure, [ 'You currently selected "Existing Window Configuration File" option ' ...
                                                          'while your window data is coming from "Automatic Window Configuration File" option. ' ...
                                                          'We will use the window data that is already loaded.'], ...
                                                          'Confirmation Warning', ...
                                                          'Options', {'Proceed', 'Cancel'}, ...
                                                          'DefaultOption', 1, 'CancelOption', 2, ...
                                                          'Icon', 'warning');

                    % if user confirm to replace, else does nothing
                    if(strcmp(selection, 'Cancel'))
                        addLog(app, app.MESSAGE_WARNING, 'User pressed cancel when trying to detect ultrasound peaks');
                        return;
                    elseif(strcmp(selection, 'Proceed'))
                        % user confirm to use the data that has been loaded
                        % (meaning that it was accidentally clicked), so
                        % let's make the radio button select the correct
                        % option.
                        app.WindowCalibRadioButton.Value=2;
                        WindowConfigurationButtonGroupSelectionChanged(app, event);
                    end
                end

                % start peak detection
                if(app.windowoption_loaded == 1)
                    addLog(app, app.MESSAGE_DEFAULT, 'Peaks detection based on "Existing Window Configuration Files" is performed');
                elseif (app.windowoption_loaded == 2)
                    addLog(app, app.MESSAGE_DEFAULT, 'Peaks detection based on "Automatic Window Configuration Files" is performed');
                end

                % define window range
                app.ust_config.WindowRange = [app.ust_config.WindowLowerBound app.ust_config.WindowUpperBound];
                % convert windows mm to windows indices
                app.ust_config.WindowRange_i = floor(app.ust_config.WindowRange / app.us_spec.index2distance_constant + 1);
                
                % signal pre-processing
                path_barkercode = strcat('data', filesep, 'kenans_barkercode.txt');
                [app.USData_allpeaks, ~] = peaks_USsignal_windowed2( app.USData, ...
                                                                     app.data_spec, ...
                                                                     app.us_spec, ...
                                                                     app.ust_config.WindowRange, ...
                                                                     app.ust_config.WindowRange_i, ...
                                                                     path_barkercode, ...
                                                                     'default');
                
                % save the peaks
                USData_allpeaks = app.USData_allpeaks;
                uisave('USData_allpeaks', 'USData_allpeaks');
                
                % plot the peaks in the m-mode axes
                probeNumber_toShow = app.ProbeNumDropDown.Value;
                timestamp_toshow   = round(app.USTimestampSlider_2.Value);
                display_peak_mmode(app.USMmodeSignalAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
                display_peak_amode(app.USAmodeSignalAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');


            % ============================================================
            % AUTOMATIC WINDOW CONFIGURATION FILES
            % ============================================================

            elseif (app.WindowCalibRadioButton.Value)

                % (!) ERROR HANDLING: check if user already process the US 
                % Data, otherwise we can't proceed the process to detect peak
                if(isempty(app.USData_processedsignal))
                    uialert(app.UIFigure, 'Ultrasound Data is not processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                    return;
                end

                % (!) ERROR HANDLING: check if the user already load the 
                % ultrasound window configuration file, if not, we can't
                % proceed the peak detection.
                if(isempty(app.ust_struct))
                    uialert(app.UIFigure, [ 'You choose "Automatic Window Configuration File" while you are not loading the data yet.' ...
                                            'Please load the ultrasound window config file (.ini) first then continue. '], ...
                                            'Data not found', 'Icon', 'error');

                    addLog(app, app.MESSAGE_WARNING, 'Peak detection canceled because of window configuration file was not loaded yet');
                    return;
                end

                % (!) ERROR HANDLING: check if the user already load the 
                % ultrasound window configuration file, but choose
                % different option
                if(app.windowoption_selected ~= app.windowoption_loaded)
                    selection = uiconfirm(app.UIFigure, [ 'You selected "Automatic Window Configuration File" option ' ...
                                                          'while your window data is coming from "Existing Window Configuration File" option. ' ...
                                                          'We will use the window data that is already loaded.'], ...
                                                          'Confirmation Warning', ...
                                                          'Options', {'Proceed', 'Cancel'}, ...
                                                          'DefaultOption', 1, 'CancelOption', 2, ...
                                                          'Icon', 'warning');

                    % if user confirm to replace, else does nothing
                    if(strcmp(selection, 'Cancel'))
                        addLog(app, app.MESSAGE_WARNING, 'User pressed cancel when trying to detect ultrasound peaks');
                        return;
                    elseif(strcmp(selection, 'Proceed'))
                        % user confirm to use the data that has been loaded
                        % (meaning that it was accidentally clicked), so
                        % let's make the radio button select the correct
                        % option.
                        app.WindowConfRadioButton.Value=1;
                        WindowConfigurationButtonGroupSelectionChanged(app, event);
                    end
                end
                
                % start peak detection
                if(app.windowoption_loaded == 1)
                    addLog(app, app.MESSAGE_DEFAULT, 'Peaks detection based on "Existing Window Configuration Files" is performed');
                elseif (app.windowoption_loaded == 2)
                    addLog(app, app.MESSAGE_DEFAULT, 'Peaks detection based on "Automatic Window Configuration Files" is performed');
                end

            % ============================================================
            % NO WINDOW (OFFLINE PROCESSING)
            % ============================================================

            elseif (app.WindowNoneRadioButton.Value)

                % (!) ERROR HANDLING: check if user already process the US 
                % Data, otherwise we can't proceed the process to detect peak
                if(isempty(app.USData_processedsignal))
                    uialert(app.UIFigure, 'Ultrasound Data is not processed yet. Please load and process the ultrasound data first.', 'Data Missing', 'Icon', 'error');
                    return;
                end

                % (!) ERROR HANDLING: check if the user already load the 
                % ultrasound window configuration file, if yes, ask for
                % confirmation, otherwise the data will be replaced.
                if(~isempty(app.ust_config))
                    selection = uiconfirm(app.UIFigure, ['You already loaded the ultrasound window config file.' ...
                                                         'If you continue with "No Window (Offline Processing)" ' ...
                                                         'the window configuration will be replaced.'], ...
                                                         'Confirmation Warning', ...
                                                         'Options', {'Replace', 'Cancel'}, ...
                                                         'DefaultOption', 2, 'CancelOption', 2, ...
                                                         'Icon', 'warning');

                    % if user confirm to replace, else does nothing
                    if(strcmp(selection, 'Cancel'))
                        addLog(app, app.MESSAGE_WARNING, 'User pressed cancel when trying to detect ultrasound peaks');
                        return;
                    end
                end

                % start peak detection 
                addLog(app, app.MESSAGE_DEFAULT, 'Peaks detection based on "No Window (Offline Processing)" is performed');
                [all_bonepeak, all_ampidx, all_window] = peaks_USsignal_offline(app.USData_processedsignal.envelope, app.data_spec, app.us_spec, 'default');

                % Get the usable timestamp. Each of the probe has starting
                % point when the peak is starting to be detected. The same
                % case as ending points. Usable timestamp defined by:
                % the data between the latest starting point within all the
                % probes and the first end point within all the probes.
                startend = [];
                for current_probe=1:size(all_bonepeak,1)
                    if(~isempty(all_bonepeak{current_probe}))
                        current_data = all_bonepeak{current_probe};
                        startend = [startend; current_data(1,1) current_data(end,1)];
                    end
                end
                app.usable_timestamp = [max(startend(:,1)), min(startend(:,2))];

                % convert the format of all_bonepeak to be compatible with
                % other functions here
                app.USData_allpeaks.sharpness   = zeros(app.data_spec.n_ust, app.data_spec.n_frames);
                app.USData_allpeaks.locations   = zeros(app.data_spec.n_ust, app.data_spec.n_frames);
                for current_probe=1:size(all_bonepeak,1)
                    if(~isempty(all_bonepeak{current_probe}))
                        currentprobe_peaklocs   = all_bonepeak{current_probe};
                        app.USData_allpeaks.locations(current_probe, currentprobe_peaklocs(:,1))   = currentprobe_peaklocs(:,2);
                        % FIX THIS!!!!!
                        currentprobe_peakampidx = all_ampidx{current_probe};
                        tmp = repmat(current_probe, size(currentprobe_peakampidx,1), 1);
                        ind = sub2ind(size(app.USData_processedsignal.envelope), tmp, currentprobe_peakampidx(:,2), currentprobe_peakampidx(:,1));
                        app.USData_allpeaks.sharpness(current_probe, currentprobe_peakampidx(:,1)) = app.USData_processedsignal.envelope(ind);
                    end
                end
    
                % initialize a table for containing the probe properties
                app.ust_config = table( 'Size', [1,3], ...
                                        'VariableTypes', ["string", "double", "double"], ...
                                        'VariableNames', ["Group", "WindowLowerBound", "WindowUpperBound"]);
                % loop through all the fields
                for i=1:app.data_spec.n_ust                    
                    % but because it is string, and the US machine somehow uses comma 
                    % separator for floating point, so we need to replace the comma as 
                    % point first, then convert it to double.
                    app.ust_config.WindowLowerBound(i) = all_window(i,1);
                    app.ust_config.WindowUpperBound(i) = all_window(i,2);
                    % remove double quote in the group string from ini fle
                    app.ust_config.Group(i) = 'NOTSET';
                end

                % plot the peaks in the m-mode axes
                probeNumber_toShow = app.ProbeNumDropDown.Value;
                timestamp_toshow   = round(app.USTimestampSlider_2.Value);
                updateUSDisplays3(app, [0 3 3 0 1], probeNumber_toShow, timestamp_toshow);

                %{
                % plot patch
                display_usabledata_mmode( app.USMmodeSignalAxes, ...
                                          app.data_spec.n_frames, ...
                                          app.us_spec.x_axis_values, ...
                                          app.usable_timestamp, ...
                                          'plot_mmode_usabledata');

                % plot the peaks in the m-mode axes
                probeNumber_toShow = app.ProbeNumDropDown.Value;
                timestamp_toshow   = round(app.USTimestampSlider_2.Value);
                display_peak_mmode(app.USMmodeSignalAxes, app.USData_allpeaks, probeNumber_toShow, 'plot_peaks_mmode');
                display_peak_amode(app.USAmodeSignalAxes, app.USData_allpeaks, 'distance', probeNumber_toShow, timestamp_toshow, 'plot_peaks_amode');
                %}
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Accelerator = 's';
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.VersionMenu = uimenu(app.AboutMenu);
            app.VersionMenu.Text = 'Version';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.UltrasoundNewTab = uitab(app.TabGroup);
            app.UltrasoundNewTab.Title = 'Ultrasound (New)';
            app.USAmodeSignalAxes = uiaxes(app.UltrasoundNewTab);
            title(app.USAmodeSignalAxes, 'Title')
            xlabel(app.USAmodeSignalAxes, 'X')
            ylabel(app.USAmodeSignalAxes, 'Y')
            zlabel(app.USAmodeSignalAxes, 'Z')
            app.USMmodeSignalAxes = uiaxes(app.UltrasoundNewTab);
            title(app.USMmodeSignalAxes, 'Title')
            ylabel(app.USMmodeSignalAxes, 'Y')
            zlabel(app.USMmodeSignalAxes, 'Z')
            app.WindowAdjustmentPanel = uipanel(app.UltrasoundNewTab);
            app.WindowAdjustmentPanel.Title = 'Window Adjustment';
            app.USWindowLBEditField = uieditfield(app.WindowAdjustmentPanel, 'text');
            app.USWindowLBEditField.Editable = 'off';
            app.USWindowLBEditField.Value = '1.000';
            app.WindowLowerBoundmmSliderLabel_2 = uilabel(app.WindowAdjustmentPanel);
            app.WindowLowerBoundmmSliderLabel_2.Text = 'Window Lower Bound (mm)';
            app.USWindowLBSlider = uislider(app.WindowAdjustmentPanel);
            app.USWindowLBSlider.Limits = [0 50];
            app.USWindowLBSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.USWindowLBSlider.ValueChangingFcn = createCallbackFcn(app, @USWindowLBSliderValueChanging, true);
            app.USWindowLBSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.USWindowLBSlider.Tooltip = {'Slide to adjust the Window Lower Bound (LB). The lower bound will always below the upper bound, if not, it will automatically shift the upper bound.'};
            app.USWindowLBSlider.Value = 1;
            app.USWindowUBEditField = uieditfield(app.WindowAdjustmentPanel, 'text');
            app.USWindowUBEditField.Editable = 'off';
            app.USWindowUBEditField.Value = '1.000';
            app.WindowUpperBoundmmSliderLabel_2 = uilabel(app.WindowAdjustmentPanel);
            app.WindowUpperBoundmmSliderLabel_2.Text = 'Window Upper Bound (mm)';
            app.USWindowUBSlider = uislider(app.WindowAdjustmentPanel);
            app.USWindowUBSlider.Limits = [0 50];
            app.USWindowUBSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.USWindowUBSlider.ValueChangingFcn = createCallbackFcn(app, @USWindowUBSliderValueChanging, true);
            app.USWindowUBSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.USWindowUBSlider.Tooltip = {'Slide to adjust the Window Upper Bound (UB). The upper bound will always above the lower bound, if not, it will automatically shift the lower bound. '};
            app.USWindowUBSlider.Value = 1;
            app.USWindowConfUITable = uitable(app.WindowAdjustmentPanel);
            app.USWindowConfUITable.ColumnName = {'LowerBound'; 'UpperBound'};
            app.USWindowConfUITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'};
            app.USWindowConfUITable.Tooltip = {'Window configuration for all ultrasound transducers'};
            app.NavigationPanel = uipanel(app.UltrasoundNewTab);
            app.NavigationPanel.Title = 'Navigation';
            app.ProbeNumPrevButton = uibutton(app.NavigationPanel, 'push');
            app.ProbeNumPrevButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeNumPrevButtonPushed, true);
            app.ProbeNumPrevButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-left.png');
            app.ProbeNumPrevButton.Tooltip = {'Previous probe'};
            app.ProbeNumPrevButton.Text = '';
            app.ProbeNumNextButton = uibutton(app.NavigationPanel, 'push');
            app.ProbeNumNextButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeNumNextButtonPushed, true);
            app.ProbeNumNextButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-right.png');
            app.ProbeNumNextButton.Enable = 'off';
            app.ProbeNumNextButton.Tooltip = {'Next probe'};
            app.ProbeNumNextButton.Text = '';
            app.ProbenumDropDownLabel = uilabel(app.NavigationPanel);
            app.ProbenumDropDownLabel.Text = 'Probe num.';
            app.ProbeNumDropDown = uidropdown(app.NavigationPanel);
            app.ProbeNumDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.ProbeNumDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.ProbeNumDropDown.ValueChangedFcn = createCallbackFcn(app, @ProbeNumDropDownValueChanged, true);
            app.ProbeNumDropDown.Tooltip = {'Choose the ultrasound transducer (probe) number'};
            app.ProbeNumDropDown.Value = 30;
            app.WindowConfigurationButtonGroup = uibuttongroup(app.UltrasoundNewTab);
            app.WindowConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowConfigurationButtonGroupSelectionChanged, true);
            app.WindowConfigurationButtonGroup.Title = 'Window Configuration';
            app.WindowCalibButton = uibutton(app.WindowConfigurationButtonGroup, 'push');
            app.WindowCalibButton.ButtonPushedFcn = createCallbackFcn(app, @WindowCalibButtonPushed, true);
            app.WindowCalibButton.Enable = 'off';
            app.WindowCalibButton.Text = 'Load';
            app.WindowCalibEditField = uieditfield(app.WindowConfigurationButtonGroup, 'text');
            app.WindowCalibEditField.Editable = 'off';
            app.WindowCalibEditField.Enable = 'off';
            app.WindowCalibEditField.Placeholder = 'D:\';
            app.WindowConfButton = uibutton(app.WindowConfigurationButtonGroup, 'push');
            app.WindowConfButton.ButtonPushedFcn = createCallbackFcn(app, @WindowConfButtonPushed, true);
            app.WindowConfButton.Text = 'Load';
            app.WindowConfEditField = uieditfield(app.WindowConfigurationButtonGroup, 'text');
            app.WindowConfEditField.Editable = 'off';
            app.WindowConfEditField.Placeholder = 'D:\';
            app.WindowNoneRadioButton = uiradiobutton(app.WindowConfigurationButtonGroup);
            app.WindowNoneRadioButton.Tooltip = {'If you want to do offline post-processing, choose this option.'};
            app.WindowNoneRadioButton.Text = 'No Window (Offline Processing)';
            app.WindowCalibRadioButton = uiradiobutton(app.WindowConfigurationButtonGroup);
            app.WindowCalibRadioButton.Tooltip = {'If you have other recording that is preserved for "depth calibration", choose this option to automatically determine the window.'};
            app.WindowCalibRadioButton.Text = 'Automatic Window Calibration';
            app.WindowConfRadioButton = uiradiobutton(app.WindowConfigurationButtonGroup);
            app.WindowConfRadioButton.Tooltip = {'If you have the window configuration file (.ini), choose this option to load it. You can obtain the file from the ultrasound machine.'};
            app.WindowConfRadioButton.Text = 'Existing Window Configuration Files';
            app.WindowConfRadioButton.Value = true;
            app.SourceSignalPanel = uipanel(app.UltrasoundNewTab);
            app.SourceSignalPanel.Title = 'Source Signal';
            app.USFilesEditFieldLabel = uilabel(app.SourceSignalPanel);
            app.USFilesEditFieldLabel.Text = 'US Files';
            app.USFilesEditField = uieditfield(app.SourceSignalPanel, 'text');
            app.USFilesEditField.Editable = 'off';
            app.USFilesEditField.Placeholder = 'D:\';
            app.USFilesLoadButton = uibutton(app.SourceSignalPanel, 'push');
            app.USFilesLoadButton.ButtonPushedFcn = createCallbackFcn(app, @USFilesLoadButtonPushed, true);
            app.USFilesLoadButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'folder.png');
            app.USFilesLoadButton.Tooltip = {'Browse and load Ultrasound Files'};
            app.USFilesLoadButton.Text = '';
            app.USFilesProcessButton = uibutton(app.SourceSignalPanel, 'push');
            app.USFilesProcessButton.ButtonPushedFcn = createCallbackFcn(app, @USFilesProcessButtonPushed, true);
            app.USFilesProcessButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'settings.png');
            app.USFilesProcessButton.Tooltip = {'Process Ultrasound Data to get M-Mode Image'};
            app.USFilesProcessButton.Text = '';
            app.DetectDepthButton_2 = uibutton(app.UltrasoundNewTab, 'push');
            app.DetectDepthButton_2.ButtonPushedFcn = createCallbackFcn(app, @DetectDepthButton_2Pushed, true);
            app.DetectDepthButton_2.Tooltip = {'Detect depth according the Window Configuration option'};
            app.DetectDepthButton_2.Text = 'Detect Depth';
            app.ExportProbeConfigButton_2 = uibutton(app.UltrasoundNewTab, 'push');
            app.ExportProbeConfigButton_2.Tooltip = {'Export the new ultrasound window configuration (.ini)'};
            app.ExportProbeConfigButton_2.Text = 'Export Probe Config';
            app.USPlayButton_2 = uibutton(app.UltrasoundNewTab, 'push');
            app.USPlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @USPlayButton_2Pushed, true);
            app.USPlayButton_2.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.png');
            app.USPlayButton_2.Text = 'Play';
            app.USPauseButton_2 = uibutton(app.UltrasoundNewTab, 'push');
            app.USPauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @USPauseButton_2Pushed, true);
            app.USPauseButton_2.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'pause.png');
            app.USPauseButton_2.Enable = 'off';
            app.USPauseButton_2.Text = 'Pause';
            app.USTimestampMaxLabel_2 = uilabel(app.UltrasoundNewTab);
            app.USTimestampMaxLabel_2.Tooltip = {'Maximum timestamp'};
            app.USTimestampMaxLabel_2.Text = '/ 100';
            app.USTimestampEditField_2 = uieditfield(app.UltrasoundNewTab, 'numeric');
            app.USTimestampEditField_2.ValueDisplayFormat = '%d';
            app.USTimestampEditField_2.Tooltip = {'Current timestamp'};
            app.USTimestampEditField_2.Value = 1;
            app.USTimestampSlider_2 = uislider(app.UltrasoundNewTab);
            app.USTimestampSlider_2.Limits = [1 100];
            app.USTimestampSlider_2.ValueChangingFcn = createCallbackFcn(app, @USTimestampSlider_2ValueChanging, true);
            app.USTimestampSlider_2.MinorTicks = [];
            app.USTimestampSlider_2.Tooltip = {'Slide the slider to adjust the timestamp'};
            app.USTimestampSlider_2.Value = 1;
            app.UltrasoundTab = uitab(app.TabGroup);
            app.UltrasoundTab.Title = 'Ultrasound';
            app.USAmodeAxes = uiaxes(app.UltrasoundTab);
            title(app.USAmodeAxes, 'Title')
            xlabel(app.USAmodeAxes, 'X')
            ylabel(app.USAmodeAxes, 'Y')
            zlabel(app.USAmodeAxes, 'Z')
            app.USMmodeAxes = uiaxes(app.UltrasoundTab);
            title(app.USMmodeAxes, 'Title')
            ylabel(app.USMmodeAxes, 'Y')
            zlabel(app.USMmodeAxes, 'Z')
            app.UltrasoundTimestampSliderLabel = uilabel(app.UltrasoundTab);
            app.UltrasoundTimestampSliderLabel.Text = 'Ultrasound Timestamp';
            app.USTimestampSlider = uislider(app.UltrasoundTab);
            app.USTimestampSlider.Limits = [1 100];
            app.USTimestampSlider.ValueChangingFcn = createCallbackFcn(app, @USTimestampSliderValueChanging, true);
            app.USTimestampSlider.MinorTicks = [];
            app.USTimestampSlider.Value = 1;
            app.DetectDepthButton = uibutton(app.UltrasoundTab, 'push');
            app.DetectDepthButton.ButtonPushedFcn = createCallbackFcn(app, @DetectDepthButtonPushed, true);
            app.DetectDepthButton.Text = 'Detect Depth';
            app.USPlayButton = uibutton(app.UltrasoundTab, 'push');
            app.USPlayButton.ButtonPushedFcn = createCallbackFcn(app, @USPlayButtonPushed, true);
            app.USPlayButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.png');
            app.USPlayButton.Text = 'Play';
            app.USPauseButton = uibutton(app.UltrasoundTab, 'push');
            app.USPauseButton.ButtonPushedFcn = createCallbackFcn(app, @USPauseButtonPushed, true);
            app.USPauseButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'pause.png');
            app.USPauseButton.Enable = 'off';
            app.USPauseButton.Text = 'Pause';
            app.ExportProbeConfigButton = uibutton(app.UltrasoundTab, 'push');
            app.ExportProbeConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ExportProbeConfigButtonPushed, true);
            app.ExportProbeConfigButton.Text = 'Export Probe Config';
            app.WindowPanel = uipanel(app.UltrasoundTab);
            app.WindowPanel.Title = 'Window';
            app.WindowLowerBoundmmSliderLabel = uilabel(app.WindowPanel);
            app.WindowLowerBoundmmSliderLabel.Text = 'Window Lower Bound (mm)';
            app.WindowUBEditField = uieditfield(app.WindowPanel, 'text');
            app.WindowUBEditField.Editable = 'off';
            app.WindowUBEditField.Value = '1.000';
            app.WindowUpperBoundmmSliderLabel = uilabel(app.WindowPanel);
            app.WindowUpperBoundmmSliderLabel.Text = 'Window Upper Bound (mm)';
            app.WindowUBSlider = uislider(app.WindowPanel);
            app.WindowUBSlider.Limits = [0 50];
            app.WindowUBSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.WindowUBSlider.ValueChangingFcn = createCallbackFcn(app, @WindowUBSliderValueChanging, true);
            app.WindowUBSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.WindowUBSlider.Value = 1;
            app.WindowLBEditField = uieditfield(app.WindowPanel, 'text');
            app.WindowLBEditField.Editable = 'off';
            app.WindowLBEditField.Value = '1.000';
            app.WindowLBSlider = uislider(app.WindowPanel);
            app.WindowLBSlider.Limits = [0 50];
            app.WindowLBSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.WindowLBSlider.ValueChangingFcn = createCallbackFcn(app, @WindowLBSliderValueChanging, true);
            app.WindowLBSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.WindowLBSlider.Value = 1;
            app.ProbeNumberDropDownLabel = uilabel(app.WindowPanel);
            app.ProbeNumberDropDownLabel.Text = 'Probe Number';
            app.ProbeNumberDropDown = uidropdown(app.WindowPanel);
            app.ProbeNumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.ProbeNumberDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.ProbeNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ProbeNumberDropDownValueChanged, true);
            app.ProbeNumberDropDown.Value = 30;
            app.ProbePrevButton = uibutton(app.WindowPanel, 'push');
            app.ProbePrevButton.ButtonPushedFcn = createCallbackFcn(app, @ProbePrevButtonPushed, true);
            app.ProbePrevButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-left.png');
            app.ProbePrevButton.Text = '';
            app.ProbeNextButton = uibutton(app.WindowPanel, 'push');
            app.ProbeNextButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeNextButtonPushed, true);
            app.ProbeNextButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-right.png');
            app.ProbeNextButton.Enable = 'off';
            app.ProbeNextButton.Text = '';
            app.UltrasoundDataPanel = uipanel(app.UltrasoundTab);
            app.UltrasoundDataPanel.Title = 'Ultrasound Data';
            app.TIFFPathBrowseButton = uibutton(app.UltrasoundDataPanel, 'push');
            app.TIFFPathBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TIFFPathBrowseButtonPushed, true);
            app.TIFFPathBrowseButton.Text = 'Browse';
            app.TIFFPathEditFieldLabel = uilabel(app.UltrasoundDataPanel);
            app.TIFFPathEditFieldLabel.Text = 'TIFF Path';
            app.TIFFPathEditField = uieditfield(app.UltrasoundDataPanel, 'text');
            app.INIFileBrowseButton = uibutton(app.UltrasoundDataPanel, 'push');
            app.INIFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @INIFileBrowseButtonPushed, true);
            app.INIFileBrowseButton.Text = 'Browse';
            app.INIFileEditFieldLabel = uilabel(app.UltrasoundDataPanel);
            app.INIFileEditFieldLabel.Text = 'INI File';
            app.INIFileEditField = uieditfield(app.UltrasoundDataPanel, 'text');
            app.LoadUSDataButton = uibutton(app.UltrasoundDataPanel, 'push');
            app.LoadUSDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUSDataButtonPushed, true);
            app.LoadUSDataButton.Text = 'Load US Data';
            app.LoadUSStatusLamp = uilamp(app.UltrasoundDataPanel);
            app.ProcessUSButton = uibutton(app.UltrasoundDataPanel, 'push');
            app.ProcessUSButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessUSButtonPushed, true);
            app.ProcessUSButton.Text = 'Process US Data';
            app.ProcessUSStatusLamp = uilamp(app.UltrasoundDataPanel);
            app.USWindowUITable = uitable(app.UltrasoundTab);
            app.USWindowUITable.ColumnName = {'LowerBound'; 'UpperBound'};
            app.USWindowUITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'};
            app.USTimestampMaxLabel = uilabel(app.UltrasoundTab);
            app.USTimestampMaxLabel.Text = '/ 100';
            app.USTimestampEditField = uieditfield(app.UltrasoundTab, 'numeric');
            app.USTimestampEditField.ValueDisplayFormat = '%d';
            app.USTimestampEditField.Value = 1;
            app.MocapTab = uitab(app.TabGroup);
            app.MocapTab.Title = 'Mocap';
            app.UIAxesMocap = uiaxes(app.MocapTab);
            title(app.UIAxesMocap, 'Title')
            xlabel(app.UIAxesMocap, 'X (cm)')
            ylabel(app.UIAxesMocap, 'Y (cm)')
            zlabel(app.UIAxesMocap, 'Z (cm)')
            app.UIAxesMocap.XLim = [-300 300];
            app.UIAxesMocap.YLim = [-250 50];
            app.UIAxesMocap.ZLim = [0 450];
            app.MocapTimestampSliderLabel = uilabel(app.MocapTab);
            app.MocapTimestampSliderLabel.Text = 'Mocap Timestamp';
            app.MocapTimestampSlider = uislider(app.MocapTab);
            app.MocapTimestampSlider.MinorTicks = [];
            app.MocapDataPanel = uipanel(app.MocapTab);
            app.MocapDataPanel.Title = 'Mocap Data';
            app.TRCLocalPathBrowseButton = uibutton(app.MocapDataPanel, 'push');
            app.TRCLocalPathBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TRCLocalPathBrowseButtonPushed, true);
            app.TRCLocalPathBrowseButton.Text = 'Browse';
            app.TRCQualisysPathBrowseButton = uibutton(app.MocapDataPanel, 'push');
            app.TRCQualisysPathBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TRCQualisysPathBrowseButtonPushed, true);
            app.TRCQualisysPathBrowseButton.Text = 'Browse';
            app.MatQualisysBrowseButton = uibutton(app.MocapDataPanel, 'push');
            app.MatQualisysBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MatQualisysBrowseButtonPushed, true);
            app.MatQualisysBrowseButton.Text = 'Browse';
            app.TRCLocalEditField = uieditfield(app.MocapDataPanel, 'text');
            app.TRCLocalEditFieldLabel = uilabel(app.MocapDataPanel);
            app.TRCLocalEditFieldLabel.Text = 'TRC  Local ';
            app.TRCQualisysEditFieldLabel = uilabel(app.MocapDataPanel);
            app.TRCQualisysEditFieldLabel.Text = 'TRC Qualisys ';
            app.TRCQualisysEditField = uieditfield(app.MocapDataPanel, 'text');
            app.MATQualisysEditFieldLabel = uilabel(app.MocapDataPanel);
            app.MATQualisysEditFieldLabel.Text = 'MAT Qualisys ';
            app.MATQualisysEditField = uieditfield(app.MocapDataPanel, 'text');
            app.HolderCalibEditFieldLabel = uilabel(app.MocapDataPanel);
            app.HolderCalibEditFieldLabel.Text = 'Holder Calib';
            app.HolderCalibEditField = uieditfield(app.MocapDataPanel, 'text');
            app.HolderCalibBrowseButton = uibutton(app.MocapDataPanel, 'push');
            app.HolderCalibBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @HolderCalibBrowseButtonPushed, true);
            app.HolderCalibBrowseButton.Text = 'Browse';
            app.LoadMocapDataButton = uibutton(app.MocapDataPanel, 'push');
            app.LoadMocapDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMocapDataButtonPushed, true);
            app.LoadMocapDataButton.Text = 'Load Mocap Data';
            app.LoadMocapStatusLamp = uilamp(app.MocapDataPanel);
            app.SynchronizeUSMocapButton = uibutton(app.MocapDataPanel, 'push');
            app.SynchronizeUSMocapButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeUSMocapButtonPushed, true);
            app.SynchronizeUSMocapButton.Text = 'Synchronize US-Mocap';
            app.SynchronizeUSMocapStatusLamp = uilamp(app.MocapDataPanel);
            app.DisplayOptionsPanel = uipanel(app.MocapTab);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.MarkersSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.MarkersSwitchLabel.Enable = 'off';
            app.MarkersSwitchLabel.Text = 'Markers';
            app.MarkersSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.MarkersSwitch.Enable = 'off';
            app.USTipsSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.USTipsSwitchLabel.Text = 'US Tips';
            app.USTipsSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.USTipsSwitch.Value = 'On';
            app.AmodeSwitchLabel = uilabel(app.DisplayOptionsPanel);
            app.AmodeSwitchLabel.Text = 'A-mode';
            app.AmodeSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.AmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @AmodeSwitchValueChanged, true);
            app.TrajectorySwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.TrajectorySwitch.ValueChangedFcn = createCallbackFcn(app, @TrajectorySwitchValueChanged, true);
            app.TrajectorySwitch.Enable = 'off';
            app.TrajectoryNumberEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.TrajectoryNumberEditField.Enable = 'off';
            app.StartDropDownLabel = uilabel(app.DisplayOptionsPanel);
            app.StartDropDownLabel.Text = 'Start';
            app.StartUSTDropDown = uidropdown(app.DisplayOptionsPanel);
            app.StartUSTDropDown.Items = {'29', '30'};
            app.StartUSTDropDown.Value = '29';
            app.EndDropDownLabel = uilabel(app.DisplayOptionsPanel);
            app.EndDropDownLabel.Text = 'End';
            app.EndUSTDropDown = uidropdown(app.DisplayOptionsPanel);
            app.EndUSTDropDown.Items = {'29', '30'};
            app.EndUSTDropDown.Value = '30';
            app.MocapPlayButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.MocapPlayButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.png');
            app.MocapPlayButton.Text = 'Play';
            app.MocapPauseButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.MocapPauseButton.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'pause.png');
            app.MocapPauseButton.Text = 'Pause';
            app.LoadDisplayOptionButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.LoadDisplayOptionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDisplayOptionButtonPushed, true);
            app.LoadDisplayOptionButton.Text = 'Load Display Option';
            app.TrajectoryLabel = uilabel(app.DisplayOptionsPanel);
            app.TrajectoryLabel.Enable = 'off';
            app.TrajectoryLabel.Text = 'Trajectory';
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.KinematicTab = uitab(app.TabGroup);
            app.KinematicTab.Title = 'Kinematic';
            app.LogsTextAreaLabel = uilabel(app.UIFigure);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end