classdef settingsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UltrasoundDisplayPanel          matlab.ui.container.Panel
        MmodeDisplayThresholdEditField  matlab.ui.control.EditField
        MmodeDisplayThresholdEditFieldLabel  matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        MHzLabel                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.EditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        SpeedofSoundEditField           matlab.ui.control.EditField
        SpeedofSoundEditFieldLabel      matlab.ui.control.Label
        UltrasoundSignalPanel           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, settings_struct)
            % Store main app object
            app.CallingApp = caller;

            % write down the initialization values to edit fields
            app.SpeedofSoundEditField.Value = num2str(settings_struct.v_sound);
            app.SampleRateEditField.Value   = num2str(settings_struct.sample_rate);
            
            app.MmodeDisplayThresholdEditField.Value   = num2str(settings_struct.mmode_display_threshold);
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % get all the settings values
            settings_struct.v_sound     = str2double(app.SpeedofSoundEditField.Value);
            settings_struct.sample_rate = str2double(app.SampleRateEditField.Value); 

            settings_struct.mmode_display_threshold = str2double(app.MmodeDisplayThresholdEditField.Value); 
            
            % Send to main object
            updateSettings(app.CallingApp, settings_struct);

            % close this settings app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UltrasoundSignalPanel = uipanel(app.UIFigure);
            app.UltrasoundSignalPanel.Title = 'Ultrasound Signal';
            app.SpeedofSoundEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedofSoundEditFieldLabel.Text = 'Speed of Sound';
            app.SpeedofSoundEditField = uieditfield(app.UIFigure, 'text');
            app.SampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.UIFigure, 'text');
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.MHzLabel = uilabel(app.UIFigure);
            app.MHzLabel.Text = 'MHz';
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.UltrasoundDisplayPanel = uipanel(app.UIFigure);
            app.UltrasoundDisplayPanel.Title = 'Ultrasound Display';
            app.MmodeDisplayThresholdEditFieldLabel = uilabel(app.UltrasoundDisplayPanel);
            app.MmodeDisplayThresholdEditFieldLabel.Text = {'M-mode Display'; 'Threshold'};
            app.MmodeDisplayThresholdEditField = uieditfield(app.UltrasoundDisplayPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settingsapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end